classdef TranscriptionModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ModelTab                       matlab.ui.container.Tab
        ModelDropDownLabel             matlab.ui.control.Label
        ModelDropDown                  matlab.ui.control.DropDown
        LoadButton                     matlab.ui.control.Button
        SaveParButton                  matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        minLabel_6                     matlab.ui.control.Label
        mRNAreleasemodelPanel          matlab.ui.container.Panel
        mRNAprocLabel                  matlab.ui.control.Label
        mRNA_proc                      matlab.ui.control.NumericEditField
        tomRNALabel                    matlab.ui.control.Label
        kmRNA                          matlab.ui.control.NumericEditField
        minLabel_5                     matlab.ui.control.Label
        minLabel_7                     matlab.ui.control.Label
        DefaultPar                     matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        minLabel                       matlab.ui.control.Label
        minLabel_2                     matlab.ui.control.Label
        minLabel_3                     matlab.ui.control.Label
        minLabel_4                     matlab.ui.control.Label
        RNAP2Label                     matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        beta                           matlab.ui.control.NumericEditField
        AbortEditFieldLabel            matlab.ui.control.Label
        Abort                          matlab.ui.control.NumericEditField
        EscapeEditFieldLabel           matlab.ui.control.Label
        Escape                         matlab.ui.control.NumericEditField
        Label_13                       matlab.ui.control.Label
        omega                          matlab.ui.control.NumericEditField
        CompletionLabel                matlab.ui.control.Label
        Process                        matlab.ui.control.NumericEditField
        RNAP2NoiseLabel                matlab.ui.control.Label
        s_rnap2                        matlab.ui.control.NumericEditField
        Ser5phNoiseLabel               matlab.ui.control.Label
        s_ser5                         matlab.ui.control.NumericEditField
        TSNoiseLabel                   matlab.ui.control.Label
        s_TS                           matlab.ui.control.NumericEditField
        ctdtoser5phratemodelPanel      matlab.ui.container.Panel
        ctdtoctdser5phLabel            matlab.ui.control.Label
        ctd_to_ser5ph                  matlab.ui.control.NumericEditField
        minLabel_8                     matlab.ui.control.Label
        TimeSeriesTab                  matlab.ui.container.Tab
        ReplotButton                   matlab.ui.control.Button
        RunningAverageWindowEditFieldLabel  matlab.ui.control.Label
        RunningAverage                 matlab.ui.control.NumericEditField
        Normalizeto95maxCheckBox       matlab.ui.control.CheckBox
        TimeSig                        matlab.ui.control.UIAxes
        AutoCrossCovariancesTab        matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        ACCTab                         matlab.ui.container.Tab
        CC33                           matlab.ui.control.UIAxes
        CC11                           matlab.ui.control.UIAxes
        CC22                           matlab.ui.control.UIAxes
        ACCNormalizationDropDownLabel  matlab.ui.control.Label
        ACCNormalizationDropDown       matlab.ui.control.DropDown
        PlottingOptionsLabel           matlab.ui.control.Label
        simulatedspotsgreyLabel        matlab.ui.control.Label
        NSpots_3                       matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        FitLabel                       matlab.ui.control.Label
        CCTab                          matlab.ui.container.Tab
        CC12                           matlab.ui.control.UIAxes
        CC23                           matlab.ui.control.UIAxes
        CC13                           matlab.ui.control.UIAxes
        DataOptionsLabel_2             matlab.ui.control.Label
        CCNormalizationDropDownLabel   matlab.ui.control.Label
        CCNormalizationDropDown        matlab.ui.control.DropDown
        InhibsTab                      matlab.ui.container.Tab
        TRfig                          matlab.ui.control.UIAxes
        NumberofSpotstoAverageEditFieldLabel  matlab.ui.control.Label
        NSpots                         matlab.ui.control.NumericEditField
        run_inhibs                     matlab.ui.control.Button
        BlockLabel                     matlab.ui.control.Label
        WeightLabel                    matlab.ui.control.Label
        beta_per                       matlab.ui.control.NumericEditField
        omega_per                      matlab.ui.control.NumericEditField
        esc_per                        matlab.ui.control.NumericEditField
        comp_per                       matlab.ui.control.NumericEditField
        abort_per                      matlab.ui.control.NumericEditField
        BurstsizeLabel                 matlab.ui.control.Label
        BurstFreqLabel                 matlab.ui.control.Label
        kescLabel                      matlab.ui.control.Label
        kabortLabel                    matlab.ui.control.Label
        kcompleteLabel                 matlab.ui.control.Label
        CTDLabel_2                     matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        TSLabel                        matlab.ui.control.Label
        k_mrna_release_per             matlab.ui.control.NumericEditField
        kmRNAreleaseLabel              matlab.ui.control.Label
        k_mrna_proc_per                matlab.ui.control.NumericEditField
        kmRNAprocLabel                 matlab.ui.control.Label
        k_ctd_to_ser5_per              matlab.ui.control.NumericEditField
        kctdtoser5phctdLabel           matlab.ui.control.Label
        Ser5phforser5phratemodelLabel  matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        StatsTab                       matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        StatisticsIntensityDistributionsTab  matlab.ui.container.Tab
        UITable2                       matlab.ui.control.Table
        mrna_hist                      matlab.ui.control.UIAxes
        ser5_hist                      matlab.ui.control.UIAxes
        rnap_hist                      matlab.ui.control.UIAxes
        NumberofSpotstoAverageEditFieldLabel_2  matlab.ui.control.Label
        NSpots_2                       matlab.ui.control.NumericEditField
        hist_simulate                  matlab.ui.control.Button
        DataLabel_3                    matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        SimulationLabel                matlab.ui.control.Label
        SignalIntensityMoleculecountHistogramcomparisonsLabel  matlab.ui.control.Label
        mRNAsignalsarecomparedwiththeLabel  matlab.ui.control.Label
        Stattable1                     matlab.ui.control.Table
        calc_stats                     matlab.ui.control.Button
        ChipSeqTab                     matlab.ui.container.Tab
        chipseq_POL2                   matlab.ui.control.UIAxes
        chipseq_SER5                   matlab.ui.control.UIAxes
        chipseq_SER5_2                 matlab.ui.control.UIAxes
        BinsizeEditFieldLabel          matlab.ui.control.Label
        BinsizeEditField               matlab.ui.control.NumericEditField
        run_chip                       matlab.ui.control.Button
        NspotsEditFieldLabel           matlab.ui.control.Label
        NspotsEditField                matlab.ui.control.NumericEditField
        KelongEditFieldLabel           matlab.ui.control.Label
        KelongEditField                matlab.ui.control.NumericEditField
        SimulatedChipExperimentsLabel  matlab.ui.control.Label
        ThemodelisrunatlongtimeperiodsLabel  matlab.ui.control.Label
        MinimaTab                      matlab.ui.container.Tab
        minima_plot                    matlab.ui.control.UIAxes
        minima_plot_2                  matlab.ui.control.UIAxes
        minima_plot_3                  matlab.ui.control.UIAxes
        minima_plot_4                  matlab.ui.control.UIAxes
        run_minima                     matlab.ui.control.Button
        detectionthresholdEditFieldLabel  matlab.ui.control.Label
        detectionthresholdEditField    matlab.ui.control.NumericEditField
        minima_plot_5                  matlab.ui.control.UIAxes
        validminimaEditFieldLabel      matlab.ui.control.Label
        validminimaEditField           matlab.ui.control.EditField
        GeneratedTrajEditFieldLabel    matlab.ui.control.Label
        GeneratedTrajEditField         matlab.ui.control.NumericEditField
        DataLabel                      matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        CTDLabel                       matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        SER5Label                      matlab.ui.control.Label
        mRNALabel                      matlab.ui.control.Label
        ModelLabel                     matlab.ui.control.Label
        IntensityScatterTab            matlab.ui.container.Tab
        ctd_ks                         matlab.ui.control.UIAxes
        ser5_ks                        matlab.ui.control.UIAxes
        ts_ks                          matlab.ui.control.UIAxes
        ksplot                         matlab.ui.control.Button
        DataLabel_2                    matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        ModelLabel_2                   matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        NspotsEditField_2Label         matlab.ui.control.Label
        nspots_intensity_scatter       matlab.ui.control.NumericEditField
        IntensityScatterPlotCorrelationsLabel  matlab.ui.control.Label
        IntensitysovertimeareoverlayedLabel  matlab.ui.control.Label
        InfoTab                        matlab.ui.container.Tab
        TriTaggedTranscriptionsystemLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        LinktopaperhereLabel           matlab.ui.control.Label
        loadingbar                     matlab.ui.control.UIAxes
        loadingbar_text                matlab.ui.control.Label
    end

    
    properties (Access = public)
        model_options % Description
        mrna
        ser5
        rnap
        mrna_rnap
        mrna_ser5
        ser5_rnap
        rnap_I
        ser5_I
        mrna_I
        ser5_data
        rnap_data
        mrna_data
        error_minima_ts
        error_minima_ser5 
        error_minima_pol2 
        minima_count_error
        
        ana_mrna
        ana_rnap
        ana_ser5
        ana_mrna_rnap
        ana_mrna_ser5
        ana_ser5_rnap
        
        sigred
        TT
        
        Model_obj
        
    end
    
    methods (Access = private)
        
        function results = run_model(app, model_type, n_samps)
           app.model_options.trypt_scale = 0;
            app.model_options.trypt_time = 0;
            Cis = [];
            app.model_options.freepars
            real_valscl
            
            
            %f = waitbar(0,'generating data from the model...');
            
            cla(app.loadingbar)
            set(app.loadingbar,'xlim',[0,1])
            pause(.1);
            ticksize = app.NSpots_3.Value;
            set(app.loadingbar_text,'Text','Generating Data')


            means = [];
            for k = 1:app.NSpots_3.Value
                %waitbar(k/app.NSpots_3.Value,app.loadingbar)
            
                rectangle(app.loadingbar, 'Position',[0,0, k/app.NSpots_3.Value  ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
                set(app.loadingbar,'xlim',[0,1])
                
                if k == 1
                    
                    real_valscl(app.model_options.freepars)
                    [C,lags,t_array,POL2,SER5,TS,POL2raw,SER5raw,TSraw] = get_stoch_corrs_Nov25(real_valscl(app.model_options.freepars),[],1,app.model_options, app.Traj_len.Value);
                    means = [means,mean(TS)];
                    
                else
                    [C,~,~,~,~,TS] = get_stoch_corrs_Nov25(real_valscl(app.model_options.freepars),[],1,app.model_options, app.Traj_len.Value);
                    means = [means,mean(TS)];
                end
                %plot(app.TimeSig,t_array,POL2,t_array,SER5,t_array,TS,'linewidth',2);
                
                Cis(:,:,k) = C;
                make_ac_and_cc_plots
                
                pause(.1);
                
            end            
        end
        
        function real_valscl = get_par(app)
            
           if strcmp(app.ModelDropDown.Value,'Simple')
                app.model_options.burst = 1;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.calibration = 0;
                
                real_valscl = [app.omega.Value,...
                    1,...
                    app.Escape.Value,...
                    app.Process.Value,...                    
                    app.beta.Value,...
                    app.Abort.Value,...
                    1,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    .9248,...
                    1.2292,...
                    1.1412,...
                    ];
                %app.model_options.freepars = [1:2,5,8:10,12:14];
 
           end
           if strcmp(app.ModelDropDown.Value, 'ser5ph rate')
               
                real_valscl = [app.omega.Value,...
                    1,...
                    app.Escape.Value,...
                    app.Process.Value,...                    
                    app.beta.Value,...
                    app.Abort.Value,...
                    1,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    .925,...
                    1.2295,...
                    1.141759,...
                    app.ctd_to_ser5ph.Value   ];
               
           end           
           
           if strcmp(app.ModelDropDown.Value, 'solo mRNA')
               
                real_valscl = [app.omega.Value,...
                    1,...
                    app.Escape.Value,...
                    app.Process.Value,...                    
                    app.beta.Value,...
                    app.Abort.Value,...
                    1,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    .954,...
                    1.2786,...
                    1.1804,...
                    app.kmRNA.Value,...
                    app.mRNA_proc.Value];
               
           end
            
        end
        
        function [S,W,x0] = setup_stoch_sim(app)
            parameters = app.get_par();
            kon = parameters(1);
            koff = 1000;%parameters(2);
            kesc = parameters(3);
            kproc = parameters(4);
            kin =  1000*parameters(5);
            kout = parameters(6);
            frac = parameters(7);
            eta_rnap = parameters(8);
            eta_ser5 = parameters(9);
            eta_ts = parameters(10);
            
            
            Nstates = 3;
            b = zeros(Nstates,1);
            b(1) = kon;
            
            c = zeros(3,3);
            c(1,1:3)=[0,1,1];
            c(2,1:3)=[0,frac,1];
            c(3,3)=1;
            
            S = zeros(3,6);
            W1 = zeros(6,3);
            W0 = zeros(6,1);
            S(1,1) = 1;  W1(1,1) = -kon; W0(1,1) = kon;
            S(1,2) = -1; W1(2,1) = koff; 
            
            S(2,3) = 1; W1(3,1) = kin; 
            S(2,4) = -1; W1(4,2) = kout; 
            
            S(2:3,5) = [-1;1]; W1(5,2) = kesc*frac; 
            S(3,6) = -1; W1(6,3) = kproc; 
            
            
            x0 = [0,0,0]';
            T_array = [0:1:1000];
            time_var = 0;
            signal_update_rate = 0;
            
            W = @(x) W1*x + W0;
                        
        end
        
        function results = replot_data_ccs(app)
            v = app.ACCNormalizationDropDown.Value;
            [mrna,ser5,rnap,~,~,~] = load_normalization_variance(1,v,'none',10);
            app.mrna = mrna;
            app.ser5 = ser5;
            app.rnap = rnap;
            
            v2  = app.CCNormalizationDropDown.Value;
            [~,~,~,mrna_rnap,mrna_ser5,ser5_rnap] = load_normalization_variance(1,'none',v2,10);
            app.mrna_rnap = mrna_rnap;
            app.mrna_ser5 = mrna_ser5;
            app.ser5_rnap = ser5_rnap;
           
            
            norm_type = app.ACCNormalizationDropDown.Value;
            if strcmp(norm_type,'G0')
                sr = app.sigred./app.sigred(:,1);
            else
                sr = app.sigred;
            end
            size(sr)
           
            hold(app.CC11,'off')
            plot(app.CC11,app.TT,sr(1,:),'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC11,'on')

             
            hold(app.CC22,'off')
            plot(app.CC22,app.TT,sr(5,:),'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC22,'on')
            
            hold(app.CC33,'off')
            plot(app.CC33,app.TT,sr(9,:),'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC33,'on')
           
             errorbar(app.CC11,app.rnap.tv(1:32),app.rnap.mn_ac, app.rnap.sem_ac,'linewidth',1,'color',[256, 0, 0]./256,'LineStyle','none','marker','.');
             plot(app.CC11,app.TT,sr(1,:),'linewidth',2,'color',[0, 0, 0]./256);
           %errorbar(app.CC11,app.rnap.tv(1:32),app.rnap.mn_ac, app.rnap.sem_ac,'linewidth',2,'color',[256, 0, 0]./256);
            errorbar(app.CC22,app.ser5.tv(1:32),app.ser5.mn_ac, app.ser5.sem_ac,'linewidth',1,'color',[0, 256, 0]./256,'LineStyle','none','marker','.');
             plot(app.CC22,app.TT,sr(5,:),'linewidth',2,'color',[0, 0, 0]./256);
            errorbar(app.CC33,app.mrna.tv(1:32),app.mrna.mn_ac, app.mrna.sem_ac,'linewidth',1,'color',[0, 0, 256]./256,'LineStyle','none','marker','.');
            plot(app.CC33,app.TT,sr(9,:),'linewidth',2,'color',[0, 0, 0]./256);
            
            hold(app.CC12,'off')
            plot(app.CC12,[-app.TT(end:-1:2);app.TT],[sr(4,end:-1:2),sr(2,:)],'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC12,'on')
            
            hold(app.CC13,'off')
            plot(app.CC13,[-app.TT(end:-1:2);app.TT],[sr(7,end:-1:2),sr(3,:)],'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC13,'on')
            
            hold(app.CC23,'off')
            plot(app.CC23,[-app.TT(end:-1:2);app.TT],[sr(8,end:-1:2),sr(6,:)],'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC23,'on')
            
            lags2 = [-10:10];
            errorbar(app.CC12,lags2, app.ser5_rnap.mn_cc, app.ser5_rnap.sem_cc,'o','MarkerSize',1,'MarkerFaceColor',[1, .5, 0],'Color',[1, .5, 0]);
            plot(app.CC12,[-app.TT(end:-1:2);app.TT],[sr(4,end:-1:2),sr(2,:)],'linewidth',2,'color','k');
            xlim(app.CC12,[-11,11])
            errorbar(app.CC13,lags2, app.mrna_rnap.mn_cc, app.mrna_rnap.sem_cc,'s','MarkerSize',1,'MarkerFaceColor',[0, 0.67, 1],'Color',[0, 0.67, 1]);
            plot(app.CC13,[-app.TT(end:-1:2);app.TT],[sr(7,end:-1:2),sr(3,:)],'linewidth',2,'color','k');
            xlim(app.CC13,[-11,11])
            errorbar(app.CC23,lags2, app.mrna_ser5.mn_cc, app.mrna_ser5.sem_cc,'s','MarkerSize',1,'MarkerFaceColor',[.5, 0, 1],'Color',[.5, 0, 1]);
            plot(app.CC23,[-app.TT(end:-1:2);app.TT],[sr(8,end:-1:2),sr(6,:)],'linewidth',2,'color','k');
            xlim(app.CC23,[-11,11])
            
        end
        
        function results = replot_data_ccs_holdon(app)
            
            v = app.ACCNormalizationDropDown.Value;
            [mrna,ser5,rnap,~,~,~] = load_normalization_variance(1,v,'none',10);
            app.mrna = mrna;
            app.ser5 = ser5;
            app.rnap = rnap;
            
            v2  = app.CCNormalizationDropDown.Value;
            [~,~,~,mrna_rnap,mrna_ser5,ser5_rnap] = load_normalization_variance(1,'none',v2,10);
            app.mrna_rnap = mrna_rnap;
            app.mrna_ser5 = mrna_ser5;
            app.ser5_rnap = ser5_rnap;
           
            
            norm_type = app.ACCNormalizationDropDown.Value;
            if strcmp(norm_type,'G0')
                sr = app.sigred./app.sigred(:,1);
            else
                sr = app.sigred;
            end


            plot(app.CC11,app.TT,sr(1,:),'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC11,'on')


            plot(app.CC22,app.TT,sr(5,:),'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC22,'on')
            
           
            plot(app.CC33,app.TT,sr(9,:),'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC33,'on')
           
             errorbar(app.CC11,app.rnap.tv(1:32),app.rnap.mn_ac, app.rnap.sem_ac,'linewidth',1,'color',[256, 0, 0]./256,'LineStyle','none','marker','.');
             plot(app.CC11,app.TT,sr(1,:),'linewidth',2,'color',[0, 0, 0]./256);
           %errorbar(app.CC11,app.rnap.tv(1:32),app.rnap.mn_ac, app.rnap.sem_ac,'linewidth',2,'color',[256, 0, 0]./256);
            errorbar(app.CC22,app.ser5.tv(1:32),app.ser5.mn_ac, app.ser5.sem_ac,'linewidth',1,'color',[0, 256, 0]./256,'LineStyle','none','marker','.');
             plot(app.CC22,app.TT,sr(5,:),'linewidth',2,'color',[0, 0, 0]./256);
            errorbar(app.CC33,app.mrna.tv(1:32),app.mrna.mn_ac, app.mrna.sem_ac,'linewidth',1,'color',[0, 0, 256]./256,'LineStyle','none','marker','.');
            plot(app.CC33,app.TT,sr(9,:),'linewidth',2,'color',[0, 0, 0]./256);
            
            
            plot(app.CC12,[-app.TT(end:-1:2);app.TT],[sr(4,end:-1:2),sr(2,:)],'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC12,'on')
            
           
            plot(app.CC13,[-app.TT(end:-1:2);app.TT],[sr(7,end:-1:2),sr(3,:)],'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC13,'on')
            
            
            plot(app.CC23,[-app.TT(end:-1:2);app.TT],[sr(8,end:-1:2),sr(6,:)],'linewidth',2,'color',[214, 24, 78]./256);
            hold(app.CC23,'on')
            
            lags2 = [-10:10];
            errorbar(app.CC12,lags2, app.ser5_rnap.mn_cc, app.ser5_rnap.sem_cc,'o','MarkerSize',1,'MarkerFaceColor',[1, .5, 0],'Color',[1, .5, 0]);
            plot(app.CC12,[-app.TT(end:-1:2);app.TT],[sr(4,end:-1:2),sr(2,:)],'linewidth',2,'color','k');
            xlim(app.CC12,[-11,11])
            errorbar(app.CC13,lags2, app.mrna_rnap.mn_cc, app.mrna_rnap.sem_cc,'s','MarkerSize',1,'MarkerFaceColor',[0, 0.67, 1],'Color',[0, 0.67, 1]);
            plot(app.CC13,[-app.TT(end:-1:2);app.TT],[sr(7,end:-1:2),sr(3,:)],'linewidth',2,'color','k');
            xlim(app.CC13,[-11,11])
            errorbar(app.CC23,lags2, app.mrna_ser5.mn_cc, app.mrna_ser5.sem_cc,'s','MarkerSize',1,'MarkerFaceColor',[.5, 0, 1],'Color',[.5, 0, 1]);
            plot(app.CC23,[-app.TT(end:-1:2);app.TT],[sr(8,end:-1:2),sr(6,:)],'linewidth',2,'color','k');
            xlim(app.CC23,[-11,11])
                        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             app.beta.Value=0.829821128978619;
            %             app.Out.Value=  0.586563822136578;
            %             app.Init.Value = 0.218952900398566;
            %             app.Abort.Value= 0.214272752317766;
            %             app.Dephos.Value= 0.172228755836466;
            %             app.Escape.Value= 0.229287719353493;
            %             app.Elong.Value=  1.683570648089907;
            %             app.omega.Value=  0.109778663023288;
            %             app.Off.Value=  0.026830096724007;
            %             app.Process.Value= 0.110832879603338;
            %             app.s_rnap2.Value= 0.01;
            %             app.s_ser5.Value= 0.01;
            %             app.s_TS.Value= 0.01;
            %
            
            %[mrna,ser5,rnap,mrna_rnap,mrna_ser5,ser5_rnap] = load_normalization_variance_gui(app.NormalizationtypeDropDown.Value);
            
            %if app.NormalizationtypeDropDown.Value = 
%             [mrna,ser5,rnap,mrna_rnap,mrna_ser5,ser5_rnap] = load_normalization_variance_gui(0,app.NormalizationtypeDropDown.Value);
%             
% 
%             [rnapi, ser5i, mrnai] = Normalize_raw_intensities(.95);
%             
%             app.rnap_I = rnapi;
%             app.ser5_I = ser5i ;
%             app.mrna_I = mrnai ;
%             app.mrna = mrna;
%             app.ser5 = ser5;
%             app.rnap = rnap;
%             app.mrna_rnap = mrna_rnap;
%             app.mrna_ser5 = mrna_ser5;
%             app.ser5_rnap = ser5_rnap;
%             app.rnap_data = reshape(app.rnap_I(1:20:end,:),1,200);
%             app.ser5_data = reshape(app.ser5_I(1:20:end,:),1,200);
%             app.mrna_data = reshape(app.mrna_I(1:20:end,:),1,200);
            %app.RunButtonPushed(app);
            
            
            addpath('./Model_files/')
            addpath('./Parameter_files/')
            app.Model_obj = SimpleModel;
            addpath('./Data_files/')
            set(app.kmRNA, 'Enable','off')
            set(app.mRNA_proc, 'Enable','off')
            set(app.ctd_to_ser5ph, 'Enable','off')
            
            set(app.loadingbar,'xtick',[],'ytick',[])
            set(app.loadingbar,'box','on')
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.loadingbar.cla
            set(app.loadingbar,'xlim',[0,1])
            
            rectangle(app.loadingbar, 'Position',[0,0, 2/4 ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
            set(app.loadingbar_text,'Text','Sampling ACC/CCs')       
            pause(.1);
            v = app.ACCNormalizationDropDown.Value;
            [mrna,ser5,rnap,~,~,~] = load_normalization_variance(1,v,'none',10);
            app.mrna = mrna;
            app.ser5 = ser5;
            app.rnap = rnap;
            
            v2  = app.CCNormalizationDropDown.Value;
            [~,~,~,mrna_rnap,mrna_ser5,ser5_rnap] = load_normalization_variance(1,'none',v2,10);
            app.mrna_rnap = mrna_rnap;
            app.mrna_ser5 = mrna_ser5;
            app.ser5_rnap = ser5_rnap;
           

            [rnapi, ser5i, mrnai] = Normalize_raw_intensities(.95);
            
            app.rnap_I = rnapi;
            app.ser5_I = ser5i ;
            app.mrna_I = mrnai ;
            app.mrna = mrna;
            app.ser5 = ser5;
            app.rnap = rnap;
            app.mrna_rnap = mrna_rnap;
            app.mrna_ser5 = mrna_ser5;
            app.ser5_rnap = ser5_rnap;
            app.rnap_data = reshape(app.rnap_I(1:20:end,:),1,200);
            app.ser5_data = reshape(app.ser5_I(1:20:end,:),1,200);
            app.mrna_data = reshape(app.mrna_I(1:20:end,:),1,200);     
            
            
            if strcmp(app.ModelDropDown.Value,'Simple')
                app.model_options.burst = 1;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.calibration = 0;
                
                real_valscl = [app.omega.Value,...
                    1,...
                    app.Escape.Value,...
                    app.Process.Value,...                    
                    app.beta.Value,...
                    app.Abort.Value,...
                    1,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    .9248,...
                    1.2292,...
                    1.1412,...
                    ];
                %app.model_options.freepars = [1:2,5,8:10,12:14];


            end
            if strcmp(app.ModelDropDown.Value,'Full')
                app.model_options.burst = 1;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.calibration = 0;
                
                real_valscl = [app.beta.Value,...
                    app.Out.Value,...
                    app.Init.Value,...
                    app.Abort.Value,...
                    app.Escape.Value,...
                    app.Dephos.Value,...
                    app.Elong.Value,...
                    app.omega.Value,...
                    app.Off.Value,...
                    app.Process.Value,...
                    0,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    ];
                app.model_options.freepars = [1:10,12:14];
                app.model_options.trypt_scale = 0;
                app.model_options.trypt = [0,0,0,0,0,0,0];
                app.model_options.trypt_mu = app.ApplicationMeanEditField.Value;
                app.model_options.trypt_var = app.ApplicationVarianceEditField.Value;
                if app.PhotobleachingCheckBox.Value() == 0
                    app.model_options.photobleach = 0;    
                else
                    app.model_options.photobleach = 1;  
                    app.model_options.freepars = [1:10,12:17];
                    real_valscl = [real_valscl, app.RNAP2pbEditField.Value,app.SER5pbEditField.Value, app.TSpbEditField.Value];
                end                
                
            end            
            
            
            if strcmp(app.ModelDropDown.Value,'PS')
                app.model_options.burst = 0;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.calibration = 0;
                app.model_options.photobleach = 0;
                
                real_valscl = [app.beta.Value,...
                    app.Out.Value,...
                    app.Init.Value,...
                    app.Abort.Value,...
                    app.Escape.Value,...
                    0,...
                    app.Elong.Value,...
                    app.omega.Value,...
                    app.Off.Value,...
                    app.Process.Value,...
                    0,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    ];
                app.model_options.freepars = [1:7,10,12:14];
                app.model_options.trypt_scale = 0;
                app.model_options.trypt = [0,0,0,0,0,0,0];
                app.model_options.trypt_mu = app.ApplicationMeanEditField.Value;
                app.model_options.trypt_var = app.ApplicationVarianceEditField.Value;
            end
            
            if strcmp(app.ModelDropDown.Value,'BPS')
                app.model_options.burst = 1;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.photobleach = 0;
                app.model_options.calibration = 0;
                
                real_valscl = [app.beta.Value,...
                    app.Out.Value,...
                    app.Init.Value,...
                    app.Abort.Value,...
                    app.Escape.Value,...
                    app.Dephos.Value,...
                    app.Elong.Value,...
                    app.omega.Value,...
                    app.Off.Value,...
                    app.Process.Value,...
                    0,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    ];
                app.model_options.freepars = [1:10,12:14];
                app.model_options.trypt_scale = 0;
                app.model_options.trypt = [0,0,0,0,0,0,0];
                app.model_options.trypt_mu = app.ApplicationMeanEditField.Value;
                app.model_options.trypt_var = app.ApplicationVarianceEditField.Value;
            end

            if strcmp(app.ModelDropDown.Value,'BPPS')
                app.model_options.burst = 1;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.photobleach = 1;
                app.model_options.calibration = 0;
                
                real_valscl = [app.beta.Value,...
                    app.Out.Value,...
                    app.Init.Value,...
                    app.Abort.Value,...
                    app.Escape.Value,...
                    0,...
                    app.Elong.Value,...
                    app.omega.Value,...
                    app.Off.Value,...
                    app.Process.Value,...
                    0,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    app.RNAP2pbEditField.Value,...
                    app.SER5pbEditField.Value,...
                    app.TSpbEditField.Value
                    ];
                app.model_options.freepars = [1:10,12:17];
                app.model_options.trypt_scale = 0;
                app.model_options.trypt = [0,0,0,0,0,0,0];
                app.model_options.trypt_mu = app.ApplicationMeanEditField.Value;
                app.model_options.trypt_var = app.ApplicationVarianceEditField.Value;
            end            
            
            app.model_options.trypt_scale = 0;
            app.model_options.trypt_time = 0;
            Cis = [];
            %app.model_options.freepars
            

            %f = waitbar(0,'generating data from the model...');
            
            %cla(app.loadingbar)
            %set(app.loadingbar,'xlim',[0,1])
            %pause(.1);
            %ticksize = app.NSpots_3.Value;
            %set(app.loadingbar_text,'Text','Generating Data')
            
            %[sigred_new2 ,TT,means,sig,TT2,mins] = get_ac_and_cc_mod_simplified(real_valscl,[0:.1:30]);
            
            sigred_new = app.Model_obj.get_sigred([0:.1:30]);


%   
            app.TT = [0:.1:30]';
            app.sigred = sigred_new;  
            app.replot_data_ccs()
            parameters = app.get_par();
            
%         
        Cis = [];
        T_array = [0:1:1200];
        rnap_ssa_acc = [];
        ser5_ssa_acc = [];
        mrna_ssa_acc = [];
        
        sr_ssa_acc = [];
        ms_ssa_acc = [];
        mr_ssa_acc = [];
        Nt = length(T_array(201:end));
        
        for k = 1:app.NSpots_3.Value
            %sol = run_single_SSA_linda(x0,S,W,T_array,time_var,signal_update_rate);
            
            [~, ~, sol_norm] = app.Model_obj.ssa_traj(T_array);
             pol2norm = sol_norm(1,201:end)';
             ser5norm = sol_norm(2,201:end)';
             tsnorm = sol_norm(3,201:end)';    
         
            %[pol2_ssa,ser5_ssa,ts_ssa,~] = get_model_intesities(sol,eta_rnap,eta_ser5,eta_ts); 
            
            V = [pol2norm,ser5norm,tsnorm];
            V = (V - repmat(mean(V),length(T_array(201:end)),1) );%./std(V,0,1);
            [C,lags] = xcorr(V);
            C(round(length(C)/2):end,:) = C(round(length(C)/2):end,:)./(Nt:-1:1)'; %Xcorr scale fix
            C(1:round(length(C)/2)-1,:) = C(1:round(length(C)/2)-1,:)./(1:1:Nt-1)';            
            Cis(:,:,k) = C;
            
            mean_rnap = C(:,1) ;
            mean_rnap = mean_rnap((end-1)/2+[1:30],1);
            mean_ser5 = C(:,5) ;
            mean_ser5 = mean_ser5((end-1)/2+[1:30],1);
            
            
            mean_mrna = C(:,9) ;
            mean_mrna = mean_mrna((end-1)/2+[1:30],1);
            
            mod_ser5_rnap = C(:,4);
            mod_ser5_rnap = mod_ser5_rnap(((length(mod_ser5_rnap)+1)/2-10):((length(mod_ser5_rnap)+1)/2)+10);
                        
            mod_mrna_ser5 = C(:,8);
            mod_mrna_ser5 = mod_mrna_ser5(((length(mod_mrna_ser5)+1)/2-10):((length(mod_mrna_ser5)+1)/2)+10);

            mod_mrna_rnap = C(:,7);
            mod_mrna_rnap = mod_mrna_rnap(((length(mod_mrna_rnap)+1)/2-10):((length(mod_mrna_rnap)+1)/2)+10);
                        
            
            [G1_rnap,G1_ser5,G1_mrna] = get_g0(mean_rnap,mean_ser5,mean_mrna,app.ACCNormalizationDropDown.Value);
            
            [G1_sr,G1_ms,G1_mr] = get_g0_cc(mod_ser5_rnap,mod_mrna_ser5,mod_mrna_rnap,app.CCNormalizationDropDown.Value);

            
            rnap_ssa_acc = [rnap_ssa_acc,mean_rnap./G1_rnap];
            ser5_ssa_acc = [ser5_ssa_acc,mean_ser5./G1_ser5];
            mrna_ssa_acc = [mrna_ssa_acc,mean_mrna./G1_mrna];
            
            sr_ssa_acc = [sr_ssa_acc,mod_ser5_rnap./G1_sr];
            ms_ssa_acc = [ms_ssa_acc,mod_mrna_ser5./G1_ms];
            mr_ssa_acc = [mr_ssa_acc,mod_mrna_rnap./G1_mr];            
            
            
        end
        
            rectangle(app.loadingbar, 'Position',[0,0, 4/4 ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
            set(app.loadingbar_text,'Text','Plotting')      
            
            hold(app.CC11,'on')
            plot(app.CC11,[0:29],rnap_ssa_acc,'linewidth',1,'color',[180, 180, 180]./256);
           

            hold(app.CC22,'on')
            plot(app.CC22,[0:29],ser5_ssa_acc,'linewidth',1,'color',[180, 180, 180]./256);
                     

            hold(app.CC13,'on')
            plot(app.CC13,[-10:10],mr_ssa_acc,'linewidth',1,'color',[180, 180, 180]./256);
            

            hold(app.CC12,'on')
            plot(app.CC12,[-10:10],sr_ssa_acc,'linewidth',1,'color',[180, 180, 180]./256);
            

            hold(app.CC23,'on')
            plot(app.CC23,[-10:10],ms_ssa_acc,'linewidth',1,'color',[180, 180, 180]./256);
                  

            hold(app.CC33,'on')
            plot(app.CC33,[0:29],mrna_ssa_acc,'linewidth',1,'color',[180, 180, 180]./256);


            app.replot_data_ccs_holdon()
            hold(app.CC11,'off')
            hold(app.CC22,'off')
            hold(app.CC33,'off')
            hold(app.CC12,'off')
            hold(app.CC13,'off')
            hold(app.CC23,'off')


            app.ReplotButtonPushed()


            err_arr = get_log_l_simplified_gui(parameters,app.mrna,app.ser5,app.rnap,app.mrna_rnap,app.mrna_ser5,app.ser5_rnap);
            d = {'rnap acc',err_arr(1);'ser5 acc',err_arr(2);'mrna acc',err_arr(3);'ser5_rnap cc',err_arr(4);'mrna_rnap cc',err_arr(5);'mrna_ser5 cc',err_arr(6);'mrna mean',err_arr(7);'mrna var',err_arr(8)};%'pol2_min',err_arr(9);'ser5_min',err_arr(10);'mrna_min',err_arr(11); 'minima count error',err_arr(12); 'Total', sum(err_arr(7:12))};
            app.UITable.Data = d;
            
             
            app.loadingbar.cla
            set(app.loadingbar,'xlim',[0,1])


        end

        % Button pushed function: LoadButton
        function Load_data(app, event)
            file = uigetfile;
            load(file)
            %app.ModelDropDown.Value = 'Simple';

                   
            %pars = [parameter_file.beta, parameter_file.abort, parameter_file.escape, parameter_file.omega, parameter_file.process,parameter_file.n_ctd,parameter_file.n_ser5,parameter_file.n_ts];%,parameter_file.koff,parameter_file.kproc,parameter_file.n_ctd,parameter_file.n_ser5,parameter_file.n_ts];
       
            drawnow;
            figure(app.UIFigure)
            if strcmp(app.ModelDropDown.Value,'Simple')
                
                pars = parameters;
                %pars = 10.^savedata(end,:);
                set(app.beta,'Value',pars(1));
                set(app.Abort,'Value',pars(2));
                set(app.Escape,'Value',pars(3));
                set(app.omega,'Value',pars(4));
                set(app.Process,'Value',pars(5));
                set(app.s_rnap2,'Value',pars(6));
                set(app.s_ser5,'Value',pars(7));
                set(app.s_TS,'Value',pars(8));

            end
            

             if strcmp(app.ModelDropDown.Value,'solo mRNA')
                
                pars = parameters;
                %pars = 10.^savedata(end,:);
                set(app.beta,'Value',pars(1));
                set(app.Abort,'Value',pars(2));
                set(app.Escape,'Value',pars(3));
                set(app.omega,'Value',pars(4));
                set(app.Process,'Value',pars(5));
                set(app.s_rnap2,'Value',pars(6));
                set(app.s_ser5,'Value',pars(7));
                set(app.s_TS,'Value',pars(8));
                set(app.mRNA_proc, 'Value',pars(end));
                set(app.kmRNA, 'Value',pars(end-1));
                

            end           
            
             if strcmp(app.ModelDropDown.Value,'ser5ph rate')
                
                pars = parameters;
                %pars = 10.^savedata(end,:);
                set(app.beta,'Value',pars(1));
                set(app.Abort,'Value',pars(2));
                set(app.Escape,'Value',pars(3));
                set(app.omega,'Value',pars(4));
                set(app.Process,'Value',pars(5));
                set(app.s_rnap2,'Value',pars(6));
                set(app.s_ser5,'Value',pars(7));
                set(app.s_TS,'Value',pars(8));
                
                set(app.ctd_to_serph, 'Value',pars(end));
                

            end                      
            
        end

        % Callback function
        function TryFittingButtonPushed(app, event)
            try_fitting(app);
            RunButtonPushed(app);
        end

        % Button pushed function: run_inhibs
        function Run_inhib(app, event)
        
            per_len = 40;
            parameters = app.get_par;
            
         
            inhibs = ones(size(parameters));
            inhibs(5) = app.beta_per.Value;
            inhibs(1) = app.omega_per.Value;
            inhibs(6) = app.abort_per.Value;
            inhibs(3) = app.esc_per.Value;
            inhibs(4) = app.comp_per.Value;
            
            if strcmp(app.ModelDropDown.Value,'ser5ph rate')
                inhibs(13) = app.k_ctd_to_ser5_per.Value;
            end
            if strcmp(app.ModelDropDown.Value,'solo mRNA')
                inhibs(13) = app.k_mrna_release_per.Value;
                inhibs(14) = app.k_mrna_proc_per.Value;
            end


            pol2_traj = [];
            ser5_traj = [];
            ts_traj = [];            
            n = app.NSpots.Value;
            for i = 1:n
                %sol = run_single_SSA_linda_inhib(x0,S,W,[0:1:1200],0,0,parameters,inhibs,1110);
                [~,sol,~] = app.Model_obj.ssa_traj_inhibs([0:1:1200],inhibs,1110 );
                
                pol2_ssa = sol(1,:)';
                ser5_ssa = sol(2,:)';
                ts_ssa = sol(3,:)';
    
                ts_ssa = sol(3,:)';


                pol2_traj = [pol2_traj; pol2_ssa'];
                ser5_traj = [ser5_traj; ser5_ssa'];
                ts_traj = [ts_traj; ts_ssa'];
            end
            tode = 0:.1:per_len-10;
            if strcmp(app.ModelDropDown.Value,'ser5ph rate')
                
            
                [~, ctd_ode, ser5_ode,ts_ode] = app.Model_obj.ana_inhibs(tode,inhibs );
            
            else
                [~, ctd_ode,ts_ode] = app.Model_obj.ana_inhibs(tode,inhibs );
                
            end
            
            t = [-1110:1:per_len-10];  
            size(t)
            size(ts_traj(1:n,1:1*1100+1*per_len+1))            
            hold(app.TRfig,'off')
            plot(app.TRfig,t, pol2_traj(1:n,1:1*1100+1*per_len+1),'r','LineWidth',1); 
            hold(app.TRfig,'on')
            plot(app.TRfig,tode,ctd_ode,'r-','LineWidth',2)     
            
            %plot(app.TRfig,t, ser5_traj(1:n,1:1*1100+1*per_len+1),'r','LineWidth',1);             

            plot(app.TRfig,t, ts_traj(1:n,1:1*1100+1*per_len+1),'b','LineWidth',1); 
            
            if strcmp(app.ModelDropDown.Value,'ser5ph rate')
                plot(app.TRfig,t, ser5_traj(1:n,1:1*1100+1*per_len+1),'g','LineWidth',1); 
            end
            
            plot(app.TRfig,[0,0],[-1,100],'k--')
            plot(app.TRfig,tode,ctd_ode,'r-','LineWidth',3)
            plot(app.TRfig,tode,ts_ode,'b-','LineWidth',3)  

            %plot(app.TRfig,tode,ctd_ode,'g-','LineWidth',3)    


            plot(app.TRfig, [-1100,0],[app.Model_obj.means(2),app.Model_obj.means(2)],'r-','LineWidth',3  )
             plot(app.TRfig, [-1100,0],[app.Model_obj.means(3),app.Model_obj.means(3)],'b-','LineWidth',3  )
             
             if strcmp(app.ModelDropDown.Value,'ser5ph rate')
                 plot(app.TRfig, [-1100,0],[app.Model_obj.means(1),app.Model_obj.means(1)],'r-','LineWidth',3  )
                 plot(app.TRfig, [-1100,0],[app.Model_obj.means(2),app.Model_obj.means(2)],'g-','LineWidth',3  )
                 plot(app.TRfig, [-1100,0],[app.Model_obj.means(3),app.Model_obj.means(3)],'b-','LineWidth',3  )    
                 
                 plot(app.TRfig,tode,ser5_ode,'g-','LineWidth',3)  
             end


            xlim(app.TRfig,[-10,30])


        end

        % Callback function
        function RunChip(app, event)
            if strcmp(app.ModelDropDown.Value,'PS')
                app.model_options.burst = 0;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.calibration = 0;
                
                real_valscl = [app.beta.Value,...
                    app.Out.Value,...
                    app.Init.Value,...
                    app.Abort.Value,...
                    app.Dephos.Value,...
                    app.Escape.Value,...
                    app.Elong.Value,...
                    app.omega.Value,...
                    app.Off.Value,...
                    app.Process.Value,...
                    0,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    ];
                app.model_options.freepars = [1:7,10,12:14];
                
                app.model_options.trypt = [0,0,0,0,0,0,0];
            end
            
            if strcmp(app.ModelDropDown.Value,'BPS')
                app.model_options.burst = 1;
                app.model_options.processing = 1;
                app.model_options.elongation_randomness = 0;
                app.model_options.shot_noise = 1;
                app.model_options.calibration = 0;
                
                real_valscl = [app.beta.Value,...
                    app.Out.Value,...
                    app.Init.Value,...
                    app.Abort.Value,...
                    app.Escape.Value,...
                    0,...
                    app.Elong.Value,...
                    app.omega.Value,...
                    app.Off.Value,...
                    app.Process.Value,...
                    0,...
                    app.s_rnap2.Value,...
                    app.s_ser5.Value,...
                    app.s_TS.Value,...
                    ];
                app.model_options.freepars = [1:10,12:14];
            end
            value = app.BinsizeEditField.Value;
            [~,~,~,chip_pol2_vec,chip_ser5_vec,chip_ts_vec] = get_stoch_corrs_Nov25(real_valscl(app.model_options.freepars),[],1,app.model_options,50000);
            
            [X,N] = hist(chip_ts_vec+ .02 + .02*randn(size(chip_ts_vec)) ,value );
            
            [X2,~] = hist(chip_ser5_vec+ .02 + .02*randn(size(chip_ser5_vec)),N);
            
            [X3,~] = hist(chip_pol2_vec+ .02 + .02*randn(size(chip_pol2_vec)),N);


            cla(app.chipseq_POL2);
            bar(app.chipseq_SER5_2,N,X/sum(X),1,'linewidth',1);
            
            bar(app.chipseq_SER5,N,X2/sum(X2),1,'linewidth',1);
            bar(app.chipseq_POL2,N,X3/sum(X3),1,'linewidth',1);
            
            app.chipseq_POL2.XLim = [0 5.5];
            app.chipseq_SER5.XLim = [0 5.5];
            app.chipseq_SER5_2.XLim = [0 5.5];
            %legend(app.chipseq_POL2,'TS','SER5','POL2')
        end

        % Callback function
        function change_par(app, event)
            value = app.ModelDropDown.Value;
            
            if strcmp(value,'Full')
                app.omega.Editable = true;
                app.Off.Editable = true;
                app.omega.FontColor = [0,0,0];
                app.Label_13.FontColor = [0,0,0];
                app.Off.FontColor = [0,0,0];
                app.OffEditFieldLabel.FontColor = [0,0,0];
                app.Process.Editable = true;
                app.Process.FontColor = [0,0,0];
                app.CompletionLabel.FontColor = [0,0,0];
                
                app.Abort.Editable = true;
                app.Init.Editable = true;
                app.Elong.Editable = true;
                app.Dephos.Editable = true;
                
                app.Abort.FontColor =  [0,0,0];
                app.AbortEditFieldLabel.FontColor = [0,0,0];
                app.Init.FontColor =  [0,0,0];
                app.InitEditFieldLabel.FontColor = [0,0,0];
                app.Elong.FontColor =  [0,0,0];
                app.ElongEditFieldLabel.FontColor = [0,0,0];
                app.Dephos.FontColor =  [0,0,0];
                app.DephosEditFieldLabel.FontColor = [0,0,0];
                              
            end            
            
            if strcmp(value,'Simple with elongation')
                app.omega.Editable = true;
                app.Off.Editable = true;
                
                app.omega.FontColor = [0,0,0];
                app.Label_13.FontColor = [0,0,0];
                
                app.Off.FontColor = [0,0,0];
                app.OffEditFieldLabel.FontColor = [0,0,0];
                
                app.Process.Editable = true;
                app.Process.FontColor = [0,0,0];
               
                
                app.CompletionLabel.FontColor = [0,0,0];
                
                app.Abort.Editable = false;
                app.Init.Editable = false;                 
                
                app.Elong.Editable = true;
                app.Dephos.Editable = false;
                
                app.Abort.FontColor = [.5,.5,.5];
                app.AbortEditFieldLabel.FontColor = [.5,.5,.5];
                
                app.Init.FontColor = [.5,.5,.5];
                app.InitEditFieldLabel.FontColor = [.5,.5,.5];
                
                app.Elong.FontColor = [0,0,0];
                app.ElongEditFieldLabel.FontColor = [0,0,0];
                
                app.Dephos.FontColor = [.5,.5,.5];
                app.DephosEditFieldLabel.FontColor = [.5,.5,.5];
                              
            end
            
            if strcmp(value,'Simple')
                app.omega.Editable = true;
                app.Off.Editable = true;
                app.omega.FontColor = [0,0,0];
                app.Label_13.FontColor = [0,0,0];
                app.Off.FontColor = [0,0,0];
                app.OffEditFieldLabel.FontColor = [0,0,0];
                app.Process.Editable = true;
                app.Process.FontColor = [0,0,0];
                app.CompletionLabel.FontColor = [0,0,0];
                
                app.Abort.Editable = false;
                app.Init.Editable = false;
                app.Elong.Editable = false;
                app.Dephos.Editable =false;
                app.Abort.FontColor = [.5,.5,.5];
                app.AbortEditFieldLabel.FontColor = [.5,.5,.5];
                app.Init.FontColor = [.5,.5,.5];
                app.InitEditFieldLabel.FontColor = [.5,.5,.5];
                app.Elong.FontColor = [.5,.5,.5];
                app.ElongEditFieldLabel.FontColor = [.5,.5,.5];
                app.Dephos.FontColor = [.5,.5,.5];
                app.DephosEditFieldLabel.FontColor = [.5,.5,.5];
                              
            end
            
            if app.PhotobleachingCheckBox.Value() == 0
                app.TSpbEditField.Editable = false;
                app.SER5pbEditField.Editable = false;
                app.RNAP2pbEditField.Editable = false; 
                
                app.TSpbEditField.FontColor = [.5,.5,.5];
                app.SER5pbEditField.FontColor = [.5,.5,.5];
                app.RNAP2pbEditField.FontColor = [.5,.5,.5];
                app.TSpbEditFieldLabel.FontColor = [.5,.5,.5];
                app.SER5pbEditFieldLabel.FontColor = [.5,.5,.5];
                app.RNAP2pbEditFieldLabel.FontColor = [.5,.5,.5];                
            else
                app.TSpbEditField.FontColor = [0,0,0];
                app.SER5pbEditField.FontColor = [0,0,0];
                app.RNAP2pbEditField.FontColor = [0,0,0];
                app.TSpbEditFieldLabel.FontColor = [0,0,0];
                app.SER5pbEditFieldLabel.FontColor = [0,0,0];
                app.RNAP2pbEditFieldLabel.FontColor = [0,0,0];                
                app.TSpbEditField.Editable = true;
                app.SER5pbEditField.Editable = true;
                app.RNAP2pbEditField.Editable = true; 


            end


            if app.NoiseCheckBox.Value() == 0
                app.s_TS.Editable = false;
                app.s_ser5.Editable = false;
                app.s_rnap2.Editable = false; 
                
                app.s_TS.FontColor = [.5,.5,.5];
                app.s_ser5.FontColor = [.5,.5,.5];
                app.s_rnap2.FontColor = [.5,.5,.5];
            else
                app.s_TS.Editable = true;
                app.s_ser5.Editable = true;
                app.s_rnap2.Editable = true; 
                
                app.s_TS.FontColor = [0,0,0];
                app.s_ser5.FontColor = [0,0,0];
                app.s_rnap2.FontColor = [0,0,0];
            
            end
            
            
            if strcmp(value,'BPPS')
                app.omega.Editable = true;
                app.Off.Editable = true;
                app.omega.FontColor = [0,0,0];
                app.Off.FontColor = [0,0,0];
                app.Process.Editable = true;
                app.Process.FontColor = [0,0,0];
                
                
                app.TSpbEditField.Editable = true;
                app.TSpbEditField.FontColor = [0,0,0];
                app.SER5pbEditField.Editable = true;
                app.SER5pbEditField.FontColor = [0,0,0];
                app.RNAP2pbEditField.Editable = true;
                app.RNAP2pbEditField.FontColor = [0,0,0];
                
            end            
            
            if strcmp(value,'BPS')
                app.omega.Editable = true;
                app.Off.Editable = true;
                app.omega.FontColor = [0,0,0];
                app.Off.FontColor = [0,0,0];
                app.Process.Editable = true;
                app.Process.FontColor = [0,0,0];
                
                app.TSpbEditField.Editable = false;
                app.TSpbEditField.FontColor = [.5,.5,.5];
                app.SER5pbEditField.Editable = false;
                app.SER5pbEditField.FontColor = [.5,.5,.5];
                app.RNAP2pbEditField.Editable = false;
                app.RNAP2pbEditField.FontColor = [.5,.5,.5];
                                
                
            end
            if strcmp(value,'S')
                app.omega.Editable = false;
                app.Off.Editable = false;
                app.omega.FontColor = [.5,.5,.5];
                app.Off.FontColor = [.5,.5,.5];
                app.Process.Editable = false;
                app.Process.FontColor = [.5,.5,.5];
                app.TSpbEditField.Editable = false;
                app.TSpbEditField.FontColor = [.5,.5,.5];
                app.SER5pbEditField.Editable = false;
                app.SER5pbEditField.FontColor = [.5,.5,.5];
                app.RNAP2pbEditField.Editable = false;
                app.RNAP2pbEditField.FontColor = [.5,.5,.5];                
            end
            if strcmp(value,'BS')
                app.omega.Editable = true;
                app.Off.Editable = true;
                app.omega.FontColor = [0,0,0];
                app.Off.FontColor = [0,0,0];
                app.Process.Editable = false;
                app.Process.FontColor = [.5,.5,.5];
                app.TSpbEditField.Editable = false;
                app.TSpbEditField.FontColor = [.5,.5,.5];
                app.SER5pbEditField.Editable = false;
                app.SER5pbEditField.FontColor = [.5,.5,.5];
                app.RNAP2pbEditField.Editable = false;
                app.RNAP2pbEditField.FontColor = [.5,.5,.5];                
            end
        end

        % Button pushed function: run_minima
        function run_minima_analysis(app, event)
            
   
            pol2_traj = [];
            ser5_traj = [];
            mrna_traj = [];
            
         
                parameters = app.get_par();
                kon = parameters(1);
                koff = parameters(2);
                kesc = parameters(3);
                kproc = parameters(4);
                kin =  parameters(5);
                kout = parameters(6);
                frac = parameters(7);
                eta_rnap = parameters(8);
                eta_ser5 = parameters(9);
                eta_ts = parameters(10);
                
                
                Nstates = 3;
                b = zeros(Nstates,1);
                b(1) = kon;
                
                c = zeros(3,3);
                c(1,1:3)=[0,1,1];
                c(2,1:3)=[0,frac,1];
                c(3,3)=1;
                
                S = zeros(3,6);
                W1 = zeros(6,3);
                W0 = zeros(6,1);
                S(1,1) = 1;  W1(1,1) = -kon; W0(1,1) = kon;
                S(1,2) = -1; W1(2,1) = koff; 
                
                S(2,3) = 1; W1(3,1) = kin; 
                S(2,4) = -1; W1(4,2) = kout; 
                
                S(2:3,5) = [-1;1]; W1(5,2) = kesc*frac; 
                S(3,6) = -1; W1(6,3) = kproc; 
                
                
                x0 = [0,0,0]';
                T_array = [0:1:1000];
                time_var = 0;
                signal_update_rate = 0;
                
                W = @(x) W1*x + W0;


            [rnapi, ser5i, mrnai] = Normalize_raw_intensities(.95);
            
            app.rnap_I = rnapi;
            app.ser5_I = ser5i ;
            app.mrna_I = mrnai ;                       
            npts_pol2 = [];
            npts_ts = [];
            npts_ser5 = [];
            for i = 1:app.GeneratedTrajEditField.Value
                T_array = [400:1:599];
                
                
                %sol = run_single_SSA_linda(x0,S,W,T_array,time_var,signal_update_rate);
                [~,~,sol] = app.Model_obj.ssa_traj(T_array);
                
                pol2norm = sol(1,:)';
                ser5norm = sol(2,:)';
                tsnorm = sol(3,:)';
            
                npts_pol2 = [npts_pol2; pol2norm'];
                npts_ts = [npts_ts; ser5norm'];      
                npts_ser5 = [npts_ser5;tsnorm'];
            end
            
            percent = .95;

%             top5_POL2 = quantile(POL2,percent);
%             
%             top5_SER5 = quantile(SER5,percent);
%             
%             top5_MRNA = quantile(TS,percent);
%             POL2_I_Norm = (POL2)./(top5_POL2);
%             SER5_I_Norm = (SER5  )./(top5_SER5);
%             MRNA_I_Norm= (TS) ./(top5_MRNA);
            

            pol2_traj = npts_pol2';
            ser5_traj = npts_ser5';
            mrna_traj = npts_ts';
            
            
            PlusMinusRange = 10; % renge of values to select after finding a local minima.
            rangeToDisplay = 5; % seconds to display in the plot it should be smaller than PlusMinusRange.
            fileDataComplete = 'Data_with_Oscillations.xlsx'; % experimental data with cells that show oscillatory behaviour.
            detectionThreshold = 0.6; % minimal threshold to detect a minimum in the signal.
            withRespectToMinValue = 'mRNA'; % 'mRNA' 'Unph'  %% Sets time interval with respect to the minim of selected signal.
            
            
            UnphFluc=xlsread('Data_with_Oscillations.xlsx','Unph');
            Ser5phFluc=xlsread('Data_with_Oscillations.xlsx','Ser5ph');
            mRNAFluc=xlsread('Data_with_Oscillations.xlsx','mRNA');
            
            [POL2_I_Norm, SER5_I_Norm, MRNA_I_Norm] = Normalize_raw_intensities(.95);
            app.loadingbar.cla
            set(app.loadingbar,'xlim',[0,1])
            rectangle(app.loadingbar, 'Position',[0,0, 2/4 ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
            set(app.loadingbar_text,'Text','Finding Minima')            
            pause(.01);           
            
            [mRNAFluc_Rave,Ser5phFluc_Rave, UnphFluc_Rave, vector_minima_mRNA,lmin_mRNA] = minimaLocation(pol2_traj,ser5_traj,mrna_traj ,app.detectionthresholdEditField.Value);
            % Section made to reduce the detection of false negatives of minimal values.

            size(pol2_traj)
            minima_sum = 0;
            vector_minima_mRNA
            for n = 1:length(vector_minima_mRNA)
                minima_sum = minima_sum + length(vector_minima_mRNA{n});
                
                
            end
            
            if minima_sum == 0
                
                
                app.validminimaEditField.Value = string(minima_sum);
                app.validminimaEditField.Editable = false;
                return
                
            end
            %selectedPairs = pairSelection (vector_minima_mRNA,vector_minima_Unph);
            
            
            [mRNAFluc_data,Ser5phFluc_data, UnphFluc_data, vector_minima_mRNA_data,lmin_mRNA_data] = minimaLocation(POL2_I_Norm,SER5_I_Norm,MRNA_I_Norm ,app.detectionthresholdEditField.Value);
            
            %selectedPairs_data = pairSelection (vector_minima_mRNA_data,vector_minima_Unph_data);
            %selectedPairs
            %if isempty(selectedPairs) == 0
            
            size(mRNAFluc_Rave)
            % Section that returns the intensity values in a range +/- N seconds with respect to the minimal values of
            [mean_mRNA, mean_Ser5ph, mean_Unph, sem_mRNA, sem_Ser5ph, sem_Unph,mRNA_mRNA] = intensitySelected_FromMinimum_InARange (mRNAFluc_Rave,UnphFluc_Rave,Ser5phFluc_Rave,vector_minima_mRNA, withRespectToMinValue, PlusMinusRange);
            % Gaussian Fits
            
            %                 mean_mRNA
            [fit_mRNA, mu_mRNA, sem_mRNA,fit_Ser5ph, mu_Ser5ph, sem_Ser5ph, fit_Unph, mu_Unph, sem_Unph]= fit_Gaussian_MainCode_2 (mean_mRNA, mean_Ser5ph, mean_Unph, sem_mRNA, sem_Ser5ph, sem_Unph, rangeToDisplay);
 
            rectangle(app.loadingbar, 'Position',[0,0, 3/4 ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
            set(app.loadingbar_text,'Text','Fitting Minima')            
            pause(.01);           
            
            
            [mean_mRNA_data, mean_Ser5ph_data, mean_Unph_data, sem_mRNA_data, sem_Ser5ph_data, sem_Unph_data,mRNA_mRNA_data] = intensitySelected_FromMinimum_InARange (mRNAFluc_data,UnphFluc_data,Ser5phFluc_data,vector_minima_mRNA_data, withRespectToMinValue, PlusMinusRange);
            % Gaussian Fits
            
            %                 mean_mRNA_data
            %                 mean_Ser5ph_data
            %                 mean_Unph_data
            
            %[fit_mRNA_data, mu_mRNA_data, sem_mRNA_data,fit_Ser5ph_data, mu_Ser5ph_data, sem_Ser5ph_data, fit_Unph_data, ~, ~]= fit_Gaussian_MainCode_2 (mean_mRNA_data, mean_Ser5ph_data, mean_Unph_data, sem_mRNA_data, sem_Ser5ph_data, sem_Unph_data, rangeToDisplay);
            
            rectangle(app.loadingbar, 'Position',[0,0, 4/4 ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
            set(app.loadingbar_text,'Text','Plotting')            
            pause(.01);           
                       
            
            plot_minima_gui
            validsum = size(mRNA_mRNA,2);
            %                 app.validminimaEditField.Editable = true;
            size(mRNA_mRNA)
            minima_sum = 0;
            for n = 1:length(vector_minima_mRNA)
                minima_sum = minima_sum + length(vector_minima_mRNA{n});
                
                
            end
            
            validsum = minima_sum;
%             
%            minima_data.mu_mRNA = mean_mRNA_data;
%             minima_data.mu_Ser5ph = mean_Ser5ph_data;
%             minima_data.mu_Unph = mean_Unph_data;
%             minima_data.sem_mRNA = sem_mRNA_data;
%             minima_data.sem_Ser5ph = sem_Unph_data;
%             minima_data.sem_Unph = sem_Unph_data;
%             minima_data.percent = minima_sum/20;
%             minima_data.thresh = .8;
%             
%                 app.error_minima_ts = sum(([minima_data.mu_mRNA(6:16) ] -  [mu_mRNA]).^2 ./[minima_data.sem_mRNA(6:16).^2]);
%     app.error_minima_ser5 = sum(([minima_data.mu_Ser5ph(6:16) ] -  [mu_Ser5ph]).^2 ./[minima_data.sem_Ser5ph(6:16).^2]);
%     app.error_minima_pol2 = sum(([minima_data.mu_Unph(6:16) ] -  [mu_Unph]).^2 ./[minima_data.sem_Unph(6:16).^2]);
%           
%     
%     app.minima_count_error = -(40*log(minima_sum) - minima_sum) +  + 107.5552;   


%             set(app.loadingbar_text,'Text','')
%             cla(app.loadingbar)
%             set(app.loadingbar,'xlim',[0,1])            
%             
             app.validminimaEditField.Editable = true;
             app.validminimaEditField.Value = string(validsum);
             app.validminimaEditField.Editable = false;
            %else
            %                 app.validminimaEditField.Editable = true;
            %                 app.validminimaEditField.Value = '0 valid traj';
            %                 app.validminimaEditField.Editable = false;
            %end
            
            
            set(app.loadingbar_text,'Text','')    
            app.loadingbar.cla
            
            
            %SelectedCell =1; % integers from 1 to 7 to select one of the 7 cells with oscillations
            %plottingTimeCourses(SelectedCell, mRNAFluc_Rave,UnphFluc_Rave,Ser5phFluc_Rave,lmin_mRNA,lmin_Ser5ph,lmin_Unph,vector_minima_mRNA,vector_minima_Ser5ph,vector_minima_Unph)
            
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            %replots the time series
                T_array = [0:1:1000];
                rng(45);
                [sol, ~, sol_norm] = app.Model_obj.ssa_traj(T_array);
                
                
%                 parameters = app.get_par();
%                 kon = parameters(1);
%                 koff = parameters(2);
%                 kesc = parameters(3);
%                 kproc = parameters(4);
%                 kin =  parameters(5);
%                 kout = parameters(6);
%                 frac = parameters(7);
%                 eta_rnap = parameters(8);
%                 eta_ser5 = parameters(9);
%                 eta_ts = parameters(10);
%                 
%                 
%                 Nstates = 3;
%                 b = zeros(Nstates,1);
%                 b(1) = kon;
%                 
%                 c = zeros(3,3);
%                 c(1,1:3)=[0,1,1];
%                 c(2,1:3)=[0,frac,1];
%                 c(3,3)=1;
%                 
%                 S = zeros(3,6);
%                 W1 = zeros(6,3);
%                 W0 = zeros(6,1);
%                 S(1,1) = 1;  W1(1,1) = -kon; W0(1,1) = kon;
%                 S(1,2) = -1; W1(2,1) = koff; 
%                 
%                 S(2,3) = 1; W1(3,1) = kin; 
%                 S(2,4) = -1; W1(4,2) = kout; 
%                 
%                 S(2:3,5) = [-1;1]; W1(5,2) = kesc*frac; 
%                 S(3,6) = -1; W1(6,3) = kproc; 
%                 
%                 
%                 x0 = [0,0,0]';
%                 T_array = [0:1:1000];
%                 time_var = 0;
%                 signal_update_rate = 0;
%                 
%                 W = @(x) W1*x + W0;
%                 rng(45)
%                 sol = run_single_SSA_linda(x0,S,W,T_array,time_var,signal_update_rate);
%                 
%                 pol2_ssa = sol(2,:)';
%                 ser5_ssa = sol(2,:)';
%                 ts_ssa = sol(3,:)';
%                 
%                 [pol2_ssa,ser5_ssa,ts_ssa,~] = get_model_intesities(sol,eta_rnap,eta_ser5,eta_ts); 
%                 
%                 [pol2norm,ser5norm,tsnorm] = Normalize_simulated_intensities(.95,pol2_ssa,ser5_ssa,ts_ssa);
%                 [sol, ~, sol_norm] = app.Model_obj.ssa_traj(T_array);
                    pol2norm = sol_norm(1,1:end)';
                    ser5norm = sol_norm(2,1:end)';
                    tsnorm = sol_norm(3,1:end)';
               
                    pol2_ssa = sol(1,1:end)';
                    ser5_ssa = sol(2,1:end)';
                    ts_ssa = sol(3,1:end)';                
                
        
        % 
        % plot(T_array(1:201),movmean(pol2norm(end-200:end),3))
        % hold on;
        % plot(T_array(1:201),movmean(ser5norm(end-200:end),3))
        % plot(T_array(1:201),movmean(tsnorm(end-200:end),3))
        
            if app.Normalizeto95maxCheckBox.Value
                
                avsol = movmean(sol_norm(:,end-200:end)',app.RunningAverage.Value);
                %avpol2 =movmean(pol2norm(end-200:end),app.RunningAverage.Value);
                %avser5 = movmean(ser5norm(end-200:end),app.RunningAverage.Value);
                %avts = movmean(tsnorm(end-200:end),app.RunningAverage.Value);
                hold(app.TimeSig,'off')
                
                h = plot(app.TimeSig, avsol,'linewidth',2); 
                set(h, {'color'}, {'r';'g';'b'});
                
            else
                avsol = movmean(sol(:,end-200:end)',app.RunningAverage.Value);
                %avpol2 =movmean(pol2norm(end-200:end),app.RunningAverage.Value);
                %avser5 = movmean(ser5norm(end-200:end),app.RunningAverage.Value);
                %avts = movmean(tsnorm(end-200:end),app.RunningAverage.Value);
                hold(app.TimeSig,'off')
                
                h = plot(app.TimeSig, avsol,'linewidth',2); 
                set(h, {'color'}, {'r';'g';'b'});
            end    
    hold(app.TimeSig,'on')

    legend(app.TimeSig,'CTD','Ser5ph','TS')
    xlim(app.TimeSig,[0,200])


        end

        % Button pushed function: run_chip
        function run_chip_ana(app, event)
        
            parameters = app.get_par();
            kon = parameters(1);
            koff = parameters(2);
            kesc = parameters(3);
            kproc = parameters(4);
            kin =  parameters(5);
            kout = parameters(6);
            frac = parameters(7);
            eta_rnap = parameters(8);
            eta_ser5 = parameters(9);
            eta_ts = parameters(10);
%             
%             
%             Nstates = 3;
%             b = zeros(Nstates,1);
%             b(1) = kon;
%             
%             c = zeros(3,3);
%             c(1,1:3)=[0,1,1];
%             c(2,1:3)=[0,frac,1];
%             c(3,3)=1;
%             
%             S = zeros(3,6);
%             W1 = zeros(6,3);
%             W0 = zeros(6,1);
%             S(1,1) = 1;  W1(1,1) = -kon; W0(1,1) = kon;
%             S(1,2) = -1; W1(2,1) = koff; 
%             
%             S(2,3) = 1; W1(3,1) = kin; 
%             S(2,4) = -1; W1(4,2) = kout; 
%             
%             S(2:3,5) = [-1;1]; W1(5,2) = kesc*frac; 
%             S(3,6) = -1; W1(6,3) = kproc; 
%             
%             
%             x0 = [0,0,0]';
%             T_array = [0:1:1000];
%             time_var = 0;
%             signal_update_rate = 0;
%             
%             W = @(x) W1*x + W0;

            cla(app.loadingbar)
            set(app.loadingbar,'xlim',[0,1])
            set(app.loadingbar_text,'Text','Generating Chip')
            pause(.1);
            npts_pol2 = [];
            npts_ts = [];
    
       
            pol2_on = [];
            pol2_off = [];
            pol2_transient = [];            
         
            for k = 1:app.NspotsEditField.Value
                rectangle(app.loadingbar, 'Position',[0,0, k/app.NspotsEditField.Value  ,1],'FaceColor',[0 .5 .5],'EdgeColor',[0 .5 .5])
                pause(.01);
                
                T_array = [0:1:20000];
                
                
                %sol = run_single_SSA_linda(x0,S,W,T_array,time_var,signal_update_rate);
                [~,  sol,~] = app.Model_obj.ssa_traj(T_array);
                pol2_ssa = sol(2,:)';
                ser5_ssa = sol(2,:)+ sol(3,:)';
                ts_ssa = sol(3,:)';
                
                pol2_on = [pol2_on, sum(pol2_ssa > 10)./20000];
                pol2_off = [pol2_off, sum(pol2_ssa < 1)./20000];
                pol2_transient = [pol2_transient, sum(pol2_ssa > 0 & pol2_ssa <= 10)./20000];
                
                
                decorr_ts = ts_ssa(400:40:20000);
                decorr_pol2 = pol2_ssa(400:40:20000);
                
                npts_pol2 = [npts_pol2, decorr_pol2'];
                npts_ts = [npts_ts, decorr_ts'];
                
            end
                        
            X = [mean(npts_pol2),mean(npts_ts)];
            
                
            time_elongating = 5.2/app.KelongEditField.Value;     
            residence = 1/app.Model_obj.kproc;
            time_processing = residence - time_elongating;
            
            frac_proc = time_processing/residence;
            processing = mean(npts_ts)*frac_proc;
            elongating = mean(npts_ts)*(1-frac_proc)/app.BinsizeEditField.Value;
            mids = ones(1,app.BinsizeEditField.Value)*elongating;
            X = [mean(npts_pol2),mids,processing];

            bursting = (npts_pol2 > 10);
            X2 = [mean(npts_pol2(bursting)),mean(npts_ts(bursting))];  
            time_elongating = 5.2/app.KelongEditField.Value;     
            residence = 1/kproc;
            time_processing = residence - time_elongating;
            
            frac_proc = time_processing/residence;
            processing = mean(npts_ts(bursting))*frac_proc;
            elongating = mean(npts_ts(bursting))*(1-frac_proc)/app.BinsizeEditField.Value;
            mids = ones(1,app.BinsizeEditField.Value)*elongating;
            X2 = [mean(npts_pol2(bursting)),mids,processing];
                    
            bursting = (npts_pol2 < 10);
            X3 = [mean(npts_pol2(bursting)),mean(npts_ts(bursting))];  
            time_elongating = 5.2/app.KelongEditField.Value;     
            residence = 1/kproc;
            time_processing = residence - time_elongating;
            
            frac_proc = time_processing/residence;
            processing = mean(npts_ts(bursting))*frac_proc;
            elongating = mean(npts_ts(bursting))*(1-frac_proc)/app.BinsizeEditField.Value;
            mids = ones(1,app.BinsizeEditField.Value)*elongating;
            X3 = [mean(npts_pol2(bursting)),mids,processing];
            
            N = linspace(0,5.2,app.BinsizeEditField.Value+2);

            cla(app.chipseq_POL2);
            bar(app.chipseq_SER5_2,N,X3,1,'linewidth',1);           
            bar(app.chipseq_SER5,N,X2,1,'linewidth',1);
            bar(app.chipseq_POL2,N,X,1,'linewidth',1);
            
            app.chipseq_POL2.XLim = [0 5.5];
            app.chipseq_SER5.XLim = [0 5.5];
            app.chipseq_SER5_2.XLim = [0 5.5];
      
            cla(app.loadingbar)
            set(app.loadingbar,'xlim',[0,1])
            
            set(app.loadingbar_text,'Text','')            
            
        end

        % Button pushed function: SaveParButton
        function save_par(app, event)
        
            %if strcmp(app.ModelDropDown.Value,'Simple')
                
                file = uiputfile('par_file.mat');
                
                parameters = app.get_par();
                
                save(file,'parameters')
                
%                 real_valscl = [app.beta.Value,...
%                     app.Abort.Value,...
%                     app.Escape.Value,...
%                     app.omega.Value,...
%                     app.Process.Value,...
%                     app.s_rnap2.Value,...
%                     app.s_ser5.Value,...
%                     app.s_TS.Value,...
%                     ];        
%         
%                 parameter_file = struct('model', 'Simple', 'beta', real_valscl(1),'abort',real_valscl(2),'escape',real_valscl(3),'omega',real_valscl(4),'process',real_valscl(5),'n_ctd',real_valscl(6),'n_ser5',real_valscl(7),'n_ts',real_valscl(8));
%                 
%                 save(file,'parameter_file')
%                 drawnow;
%                 figure(app.UIFigure)


        end

        % Button pushed function: ksplot
        function ksplotButtonPushed(app, event)
         
                parameters = app.get_par();
                kon = parameters(1);
                koff = parameters(2);
                kesc = parameters(3);
                kproc = parameters(4);
                kin =  parameters(5);
                kout = parameters(6);
                frac = parameters(7);
                eta_rnap = parameters(8);
                eta_ser5 = parameters(9);
                eta_ts = parameters(10);
                
                
                Nstates = 3;
                b = zeros(Nstates,1);
                b(1) = kon;
                
                c = zeros(3,3);
                c(1,1:3)=[0,1,1];
                c(2,1:3)=[0,frac,1];
                c(3,3)=1;
                
                S = zeros(3,6);
                W1 = zeros(6,3);
                W0 = zeros(6,1);
                S(1,1) = 1;  W1(1,1) = -kon; W0(1,1) = kon;
                S(1,2) = -1; W1(2,1) = koff; 
                
                S(2,3) = 1; W1(3,1) = kin; 
                S(2,4) = -1; W1(4,2) = kout; 
                
                S(2:3,5) = [-1;1]; W1(5,2) = kesc*frac; 
                S(3,6) = -1; W1(6,3) = kproc; 
                
                
                x0 = [0,0,0]';
                T_array = [0:1:1000];
                time_var = 0;
                signal_update_rate = 0;
                
                W = @(x) W1*x + W0;


            [rnapi, ser5i, mrnai] = Normalize_raw_intensities(.95);
            
            app.rnap_I = rnapi;
            app.ser5_I = ser5i ;
            app.mrna_I = mrnai ;                       
            npts_pol2 = [];
            npts_ts = [];
            npts_ser5 = [];
            for i = 1:app.nspots_intensity_scatter.Value
                
                T_array = [400:1:599];
                
                
                %sol = run_single_SSA_linda(x0,S,W,T_array,time_var,signal_update_rate);
                [~,~,sol] = app.Model_obj.ssa_traj(T_array);
                
                pol2norm = sol(1,:)';
                ser5norm = sol(2,:)';
                tsnorm = sol(3,:)';
            
                npts_pol2 = [npts_pol2; pol2norm'];
                npts_ts = [npts_ts; ser5norm'];      
                npts_ser5 = [npts_ser5;tsnorm'];
                
                
            end


              hold(app.ctd_ks, "off")
              hold(app.ser5_ks, "off")
              hold(app.ts_ks, "off")
              
              ksdensity(app.ctd_ks,[npts_pol2(:),npts_ser5(:)],'PlotFcn','contour') 
              ksdensity(app.ser5_ks,[npts_pol2(:),npts_ts(:)],'PlotFcn','contour') 
              ksdensity(app.ts_ks,[npts_ts(:),npts_ser5(:)],'PlotFcn','contour') 
              
              hold(app.ctd_ks, "on")
                    size(app.rnap_I)
                    size(app.ser5_I)
              for i =1:20
                     scatter(app.ctd_ks, app.rnap_I(:,i),app.ser5_I(:,i),'.','MarkerFaceColor',[.2,.2,.2],'MarkerEdgeColor',[.2,.2,.2],'MarkerFaceAlpha',.1,'MarkerEdgeAlpha',.1)
                     
              end
              
              hold(app.ser5_ks, "on")
              for i =1:app.nspots_intensity_scatter.Value
              scatter(app.ser5_ks,app.rnap_I(:,i),app.mrna_I(:,i),'.','MarkerFaceColor',[.2,.2,.2],'MarkerFaceAlpha',.1,'MarkerEdgeColor',[.2,.2,.2],'MarkerEdgeAlpha',.1)
              end
              
              
              hold(app.ts_ks, "on")
              for i =1:app.nspots_intensity_scatter.Value
              scatter(app.ts_ks, app.mrna_I(:,i),app.ser5_I(:,i),'.','MarkerFaceColor',[.2,.2,.2],'MarkerFaceAlpha',.1,'MarkerEdgeColor',[.2,.2,.2],'MarkerEdgeAlpha',.1)              
              end
              
              ksdensity(app.ctd_ks,[npts_pol2(:),npts_ser5(:)],'PlotFcn','contour') 
              ksdensity(app.ser5_ks,[npts_pol2(:),npts_ts(:)],'PlotFcn','contour') 
              ksdensity(app.ts_ks,[npts_ts(:),npts_ser5(:)],'PlotFcn','contour')                             
        end

        % Button pushed function: hist_simulate
        function generate_hist(app, event)
            
            % Generates the intensity histograms
            
            [drnap_I, dser5_I, dmrna_I] = Normalize_raw_intensities(.95);   %get the normalized intensities from the data 95 percentile
            drnap_data = reshape(drnap_I(1:20:end,:),1,200);  %rnap_data sorted by 20 time incrementes (decorrelated) this was for histograms
            dser5_data = reshape(dser5_I(1:20:end,:),1,200);
            dmrna_data = reshape(dmrna_I(1:20:end,:),1,200);        

            
            data = xlsread('Nasc_TranscriptsCount.xlsx');
            dmrna_hist = data(:,1);
            dmrna_hist = dmrna_hist(1:end-5);


            pol2_ssa_traj = [];
            ser5_ssa_traj = [];
            ts_ssa_traj = [];
            mrnai_traj = [];
            rnap_c = [];
            ser5_c = [];
            mrna_c = [];
            for i = 1:app.NSpots_2.Value
                %sol = run_single_SSA_linda(x0,S,W,[0:1:2000],0,0);
                [~, sol_no_noise, sol_norm] = app.Model_obj.ssa_traj([0:1:2000]);
                pol2_ssa = sol_no_noise(1,1:end)';
                ts_ssa = sol_no_noise(3,1:end)';
                pol2norm = sol_norm(1,1:end)';
                ser5norm = sol_norm(2,1:end)';
                tsnorm = sol_norm(3,1:end)';
            
                
%                 pol2_ssa = sol(2,:)';
%                 ser5_ssa = sol(2,:)';
%                 ts_ssa = sol(3,:)';
%                 
                 rnap_c = [rnap_c,pol2_ssa+ts_ssa ];
                 mrna_c = [mrna_c, ts_ssa];
                 mrnai = ts_ssa;
%                 


%              [pol2_ssa,ser5_ssa,ts_ssa,~] = get_model_intesities(sol,eta_rnap,eta_ser5,eta_ts);   
%             
%                 %mrnai = ts_ssa;
%             
%             
%             
%                 [pol2norm,ser5norm,tsnorm] = Normalize_simulated_intensities(.95,pol2_ssa,ser5_ssa,ts_ssa);
            
                
                pol2_ssa_traj = [pol2_ssa_traj, pol2norm(1:200:end)];
                ser5_ssa_traj = [ser5_ssa_traj, ser5norm(1:200:end)];
                %ts_ssa_traj = [ts_ssa_traj, ts_ssa(1:200:end)];
                mrnai_traj = [mrnai_traj, ts_ssa(1:200:end)];

                pol2_traj = pol2_ssa_traj';
                ser5_traj = ser5_ssa_traj';
                %ts_traj = ts_ssa_traj';
                      
                hold(app.rnap_hist,'off'); 
                [x,n] = hist(reshape(pol2_traj.',1,[]),30);
                [11, 252, 3]./256;
                [218, 51, 255]./256;
                x1 = histogram(app.rnap_hist,reshape(pol2_traj.',1,[]),n,'Normalization','probability','FaceColor',[11, 252, 3]./256,'FaceAlpha',.5,'linewidth',.01,'edgecolor',[11, 252, 3]./256);%,'DisplayStyle','stairs')
                hold(app.rnap_hist,'on');                             
                x3 = histogram(app.rnap_hist, drnap_data ,n,'Normalization','probability','FaceColor',[218, 51, 255]./256,'FaceAlpha',.5,'linewidth',.01,'edgecolor',[218, 51, 255]./256);%,'DisplayStyle','stairs')

                hold(app.ser5_hist,'off');
                [~,n] = hist(reshape(ser5_traj.',1,[]),30);
                x1 = histogram(app.ser5_hist, reshape(ser5_traj.',1,[]),n,'Normalization','probability','FaceColor',[11, 252, 3]./256,'FaceAlpha',.5,'linewidth',.01,'edgecolor',[11, 252, 3]./256);%,'DisplayStyle','stairs')
                hold(app.ser5_hist,'on');
                x3 = histogram(app.ser5_hist,dser5_data ,n,'Normalization','probability','FaceColor',[218, 51, 255]./256,'FaceAlpha',.5,'linewidth',.01,'edgecolor',[218, 51, 255]./256);%,'DisplayStyle','stairs')
                                                
                [~,n] = hist(mrnai_traj,30);
                
                hold(app.mrna_hist,'off');
                x1 = histogram(app.mrna_hist,mrnai_traj,n,'Normalization','probability','FaceColor',[11, 252, 3]./256,'FaceAlpha',.5,'linewidth',.01,'edgecolor',[11, 252, 3]./256);%'DisplayStyle','stairs')
                hold(app.mrna_hist,'on');
                %histogram(mrna_hist ,n,'Normalization','probability','FaceColor','none','FaceAlpha',.2,'linewidth',3,'edgecolor',[218, 51, 255]./256,'DisplayStyle','stairs')
                x3 = histogram(app.mrna_hist,dmrna_hist ,n,'Normalization','probability','FaceColor',[218, 51, 255]./256,'FaceAlpha',.5,'linewidth',.01,'edgecolor',[218, 51, 255]./256);%,'DisplayStyle','stairs')
                                                  
            end
           
             pol2_mean = mean(reshape(rnap_c.',1,[]));
             
             ser5_mean = mean(reshape(rnap_c.',1,[])) ;
             
             ts_mean = mean(reshape(mrna_c.',1,[]));
             
             d = {ts_mean,ser5_mean,pol2_mean};
             app.UITable2.Data = d;            
            
        end

        % Value changed function: ACCNormalizationDropDown
        function acc_norm_changed(app, event)
            value = app.ACCNormalizationDropDown.Value;
            

            [mrna,ser5,rnap,~,~,~] = load_normalization_variance(1,value,'none',10);
            app.mrna = mrna;
            app.ser5 = ser5;
            app.rnap = rnap;
            
            app.replot_data_ccs()

        end

        % Value changed function: CCNormalizationDropDown
        function replot_ccs(app, event)
            value = app.CCNormalizationDropDown.Value;
            
            [~,~,~,mrna_rnap,mrna_ser5,ser5_rnap] = load_normalization_variance(1,'none',value,10);
            app.mrna_rnap = mrna_rnap;
            app.mrna_ser5 = mrna_ser5;
            app.ser5_rnap = ser5_rnap;
                        
            app.replot_data_ccs()
        end

        % Button pushed function: calc_stats
        function fill_in_stats(app, event)

            d = app.Model_obj.stats;
             
            %d2 = {'Burst Fraction',0,'---';
               %   '% Time spent on',0,'---';
                %  'mRNA transcribing', 0,'molecules';
                 % 'mRNA processing',0,'molecules';
                  %'Time spent transcribing',0,'min'; 
                  %'Time spent processing', 0,'min';
                  %'# phos before escape',0,'events';
                  %'# phos before leave', 0,'events';};
            
            
            app.Stattable1.Data = d;    
           
        end

        % Value changed function: beta
        function update_model_beta(app, event)
            app.Model_obj.kin = 1000*app.beta.Value;


        end

        % Value changed function: omega
        function update_model_omega(app, event)
            app.Model_obj.kon = app.omega.Value;
            
        end

        % Value changed function: Abort
        function update_model_abort(app, event)
            app.Model_obj.kout = app.Abort.Value;
            
        end

        % Value changed function: Escape
        function update_model_escape(app, event)
            app.Model_obj.kesc = app.Escape.Value;
            
        end

        % Value changed function: Process
        function update_model_comp(app, event)
            app.Model_obj.kproc = app.Process.Value;
            
        end

        % Value changed function: s_rnap2
        function update_model_etap2(app, event)
            app.Model_obj.eta_rnap = app.s_rnap2.Value;
            
        end

        % Value changed function: s_ser5
        function update_model_etas5(app, event)
            app.Model_obj.eta_ser5 = app.s_ser5.Value;
            
        end

        % Value changed function: s_TS
        function update_model_etats(app, event)
            app.Model_obj.eta_ts = app.s_TS.Value;
            
        end

        % Value changed function: ModelDropDown
        function switch_model(app, event)
            value = app.ModelDropDown.Value;
            
            
           if strcmp(app.ModelDropDown.Value,'Simple')
                parameters = [0.433596648003337,1,0.666624850282066,0.198727194675144,15.4054402060124,0.777851806420281,1,1.98448561566709,1.42073542241570,0.408137598525255,0.924805163389989,1.22915843769510,1.14124029524139]; %(1/min) Rate of concentration reduction
  
                app.Model_obj = SimpleModel;
                app.update_model_beta()
                
                set(app.kmRNA, 'Enable','off')
                set(app.mRNA_proc, 'Enable','off') 
                set(app.ctd_to_ser5ph, 'Enable','off')


                %app.model_options.freepars = [1:2,5,8:10,12:14];
 
           end
            if strcmp(app.ModelDropDown.Value,'solo mRNA')
                app.Model_obj = mRNASoloModel;


                app.update_model_beta()
                
                set(app.kmRNA, 'Enable','on')      
                set(app.mRNA_proc, 'Enable','on')      
                set(app.ctd_to_ser5ph, 'Enable','off')
           
            end
            
            if strcmp(app.ModelDropDown.Value,'ser5ph rate')
                app.Model_obj = RNAP_SER5_SplitModel;


                app.update_model_beta()
                
                set(app.kmRNA, 'Enable','off')
                set(app.mRNA_proc, 'Enable','off')   
                set(app.ctd_to_ser5ph, 'Enable','on')
           
            end            
            
            
        end

        % Value changed function: mRNA_proc
        function update_model_mrnaproc(app, event)
            app.Model_obj.k_proc_mRNAsolo = app.mRNA_proc.Value;
            
        end

        % Value changed function: kmRNA
        function model_update_kmrna(app, event)
            app.Model_obj.k_release_mrna = app.kmRNA.Value;
            
        end

        % Button pushed function: DefaultPar
        function set_default_par(app, event)
           if strcmp(app.ModelDropDown.Value,'Simple')
                parameters = [0.433596648003337,1,0.666624850282066,0.198727194675144,15.4054402060124,0.777851806420281,1,1.98448561566709,1.42073542241570,0.408137598525255,0.924805163389989,1.22915843769510,1.14124029524139]; %(1/min) Rate of concentration reduction
  
                %app.Model_obj = SimpleModel;
                app.omega.Value = parameters(1);
                app.beta.Value = parameters(5);
                app.Escape.Value = parameters(3);
                app.Process.Value = parameters(4);
                app.Abort.Value = parameters(6);
                app.s_rnap2.Value = parameters(8);
                app.s_ser5.Value = parameters(9);
                app.s_TS.Value = parameters(10);
                app.update_model_beta()
           end
           
           if strcmp(app.ModelDropDown.Value,'solo mRNA')
               parameters = [0.468589608485049,1,0.762024398423308,0.213465723931234,15.0442552365580,0.859806991563019,1,1.98449877917723,1.36709925921224,0.306185190421644,0.954166285001831,1.27859176402626,1.18040118003930,0.0860316220246684,0.241856551720563]; %(1/min) Rate of concentration reduction
            app.omega.Value = parameters(1);
            app.beta.Value = parameters(5);
            app.Escape.Value = parameters(3);
            app.Process.Value = parameters(4);
            app.Abort.Value = parameters(6);
            app.s_rnap2.Value = parameters(8);
            app.s_ser5.Value = parameters(9);
            app.s_TS.Value = parameters(10);
            app.kmRNA.Value = parameters(end-1);
            app.mRNA_proc.Value = parameters(end);
            app.update_model_beta()
               
           end
           if strcmp(app.ModelDropDown.Value,'ser5ph rate')
               parameters = [0.433596648003337,1,0.666624850282066,0.198727194675144,15.4054402060124,0.777851806420281,1,1.98448561566709,1.42073542241570,0.408137598525255,0.924805163389989,1.22915843769510,1.14124029524139]; %(1/min) Rate of concentration reduction
            app.omega.Value = parameters(1);
            app.beta.Value = parameters(5);
            app.Escape.Value = parameters(3);
            app.Process.Value = parameters(4);
            app.Abort.Value = parameters(6);
            app.s_rnap2.Value = parameters(8);
            app.s_ser5.Value = parameters(9);
            app.s_TS.Value = parameters(10);
            app.ctd_to_ser5ph.Value = parameters(end);
            
            app.update_model_beta()
               
           end                


        end

        % Value changed function: ctd_to_ser5ph
        function model_update_kctdser5(app, event)
            app.Model_obj.k_ctd_to_ser5 = app.ctd_to_ser5ph.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ModelDropDownLabel = uilabel(app.ModelTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.ModelTab);
            app.ModelDropDown.Items = {'Simple', 'solo mRNA', 'ser5ph rate'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @switch_model, true);
            app.ModelDropDown.Value = 'Simple';
            app.LoadButton = uibutton(app.ModelTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Load_data, true);
            app.LoadButton.Text = 'Load Par';
            app.SaveParButton = uibutton(app.ModelTab, 'push');
            app.SaveParButton.ButtonPushedFcn = createCallbackFcn(app, @save_par, true);
            app.SaveParButton.Text = 'Save Par';
            app.Image2 = uiimage(app.ModelTab);
            app.Image2.ImageSource = 'simplemodel.png';
            app.minLabel_6 = uilabel(app.ModelTab);
            app.minLabel_6.Text = '1/min';
            app.mRNAreleasemodelPanel = uipanel(app.ModelTab);
            app.mRNAreleasemodelPanel.Title = 'mRNA release model';
            app.mRNAprocLabel = uilabel(app.mRNAreleasemodelPanel);
            app.mRNAprocLabel.Text = 'mRNA proc';
            app.mRNA_proc = uieditfield(app.mRNAreleasemodelPanel, 'numeric');
            app.mRNA_proc.Limits = [0 Inf];
            app.mRNA_proc.ValueChangedFcn = createCallbackFcn(app, @update_model_mrnaproc, true);
            app.mRNA_proc.Value = 0.2418;
            app.tomRNALabel = uilabel(app.mRNAreleasemodelPanel);
            app.tomRNALabel.Text = 'to mRNA';
            app.kmRNA = uieditfield(app.mRNAreleasemodelPanel, 'numeric');
            app.kmRNA.Limits = [0 Inf];
            app.kmRNA.ValueChangedFcn = createCallbackFcn(app, @model_update_kmrna, true);
            app.kmRNA.Value = 0.086;
            app.minLabel_5 = uilabel(app.mRNAreleasemodelPanel);
            app.minLabel_5.Text = '1/min';
            app.minLabel_7 = uilabel(app.mRNAreleasemodelPanel);
            app.minLabel_7.Text = '1/min';
            app.DefaultPar = uibutton(app.ModelTab, 'push');
            app.DefaultPar.ButtonPushedFcn = createCallbackFcn(app, @set_default_par, true);
            app.DefaultPar.Text = 'Defaults';
            app.Panel = uipanel(app.ModelTab);
            app.minLabel = uilabel(app.Panel);
            app.minLabel.Text = '1/min';
            app.minLabel_2 = uilabel(app.Panel);
            app.minLabel_2.Text = '1/min';
            app.minLabel_3 = uilabel(app.Panel);
            app.minLabel_3.Text = '1/min';
            app.minLabel_4 = uilabel(app.Panel);
            app.minLabel_4.Text = '1/min';
            app.RNAP2Label = uilabel(app.Panel);
            app.RNAP2Label.Text = 'RNAP2';
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = ' β';
            app.beta = uieditfield(app.Panel, 'numeric');
            app.beta.Limits = [0 Inf];
            app.beta.ValueChangedFcn = createCallbackFcn(app, @update_model_beta, true);
            app.beta.Value = 15.4;
            app.AbortEditFieldLabel = uilabel(app.Panel);
            app.AbortEditFieldLabel.Text = 'Abort';
            app.Abort = uieditfield(app.Panel, 'numeric');
            app.Abort.Limits = [0 Inf];
            app.Abort.ValueChangedFcn = createCallbackFcn(app, @update_model_abort, true);
            app.Abort.Value = 0.78;
            app.EscapeEditFieldLabel = uilabel(app.Panel);
            app.EscapeEditFieldLabel.Text = 'Escape';
            app.Escape = uieditfield(app.Panel, 'numeric');
            app.Escape.Limits = [0 Inf];
            app.Escape.ValueChangedFcn = createCallbackFcn(app, @update_model_escape, true);
            app.Escape.Value = 0.67;
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = 'ω';
            app.omega = uieditfield(app.Panel, 'numeric');
            app.omega.Limits = [0 Inf];
            app.omega.ValueChangedFcn = createCallbackFcn(app, @update_model_omega, true);
            app.omega.Value = 0.43;
            app.CompletionLabel = uilabel(app.Panel);
            app.CompletionLabel.Text = 'Completion';
            app.Process = uieditfield(app.Panel, 'numeric');
            app.Process.Limits = [0 Inf];
            app.Process.ValueChangedFcn = createCallbackFcn(app, @update_model_comp, true);
            app.Process.Value = 0.2;
            app.RNAP2NoiseLabel = uilabel(app.Panel);
            app.RNAP2NoiseLabel.Text = 'RNAP2 Noise';
            app.s_rnap2 = uieditfield(app.Panel, 'numeric');
            app.s_rnap2.Limits = [0 Inf];
            app.s_rnap2.ValueChangedFcn = createCallbackFcn(app, @update_model_etap2, true);
            app.s_rnap2.Value = 1.984;
            app.Ser5phNoiseLabel = uilabel(app.Panel);
            app.Ser5phNoiseLabel.Text = 'Ser5ph Noise';
            app.s_ser5 = uieditfield(app.Panel, 'numeric');
            app.s_ser5.Limits = [0 Inf];
            app.s_ser5.ValueChangedFcn = createCallbackFcn(app, @update_model_etas5, true);
            app.s_ser5.Value = 1.402;
            app.TSNoiseLabel = uilabel(app.Panel);
            app.TSNoiseLabel.Text = 'TS Noise';
            app.s_TS = uieditfield(app.Panel, 'numeric');
            app.s_TS.Limits = [0 Inf];
            app.s_TS.ValueChangedFcn = createCallbackFcn(app, @update_model_etats, true);
            app.s_TS.Value = 0.408;
            app.ctdtoser5phratemodelPanel = uipanel(app.ModelTab);
            app.ctdtoser5phratemodelPanel.Title = 'ctd to ser5ph rate model';
            app.ctdtoctdser5phLabel = uilabel(app.ctdtoser5phratemodelPanel);
            app.ctdtoctdser5phLabel.Text = 'ctd to ctd+ser5ph';
            app.ctd_to_ser5ph = uieditfield(app.ctdtoser5phratemodelPanel, 'numeric');
            app.ctd_to_ser5ph.Limits = [0 Inf];
            app.ctd_to_ser5ph.ValueChangedFcn = createCallbackFcn(app, @model_update_kctdser5, true);
            app.ctd_to_ser5ph.Value = 10;
            app.minLabel_8 = uilabel(app.ctdtoser5phratemodelPanel);
            app.minLabel_8.Text = '1/min';
            app.TimeSeriesTab = uitab(app.TabGroup);
            app.TimeSeriesTab.Title = 'Time Series';
            app.ReplotButton = uibutton(app.TimeSeriesTab, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.RunningAverageWindowEditFieldLabel = uilabel(app.TimeSeriesTab);
            app.RunningAverageWindowEditFieldLabel.Text = 'Running Average Window';
            app.RunningAverage = uieditfield(app.TimeSeriesTab, 'numeric');
            app.RunningAverage.Limits = [1 200];
            app.RunningAverage.RoundFractionalValues = 'on';
            app.RunningAverage.Value = 1;
            app.Normalizeto95maxCheckBox = uicheckbox(app.TimeSeriesTab);
            app.Normalizeto95maxCheckBox.Text = 'Normalize to 95% max';
            app.TimeSig = uiaxes(app.TimeSeriesTab);
            title(app.TimeSig, 'Time Series Experiment')
            xlabel(app.TimeSig, 'time (min)')
            ylabel(app.TimeSig, 'Signal')
            app.AutoCrossCovariancesTab = uitab(app.TabGroup);
            app.AutoCrossCovariancesTab.Title = 'Auto/Cross Covariances';
            app.TabGroup3 = uitabgroup(app.AutoCrossCovariancesTab);
            app.ACCTab = uitab(app.TabGroup3);
            app.ACCTab.Title = 'ACC';
            app.CC33 = uiaxes(app.ACCTab);
            title(app.CC33, 'mRNA ACC')
            xlabel(app.CC33, 'lag (min)')
            ylabel(app.CC33, 'mRNA')
            app.CC11 = uiaxes(app.ACCTab);
            title(app.CC11, 'RNAP2 ACC')
            xlabel(app.CC11, 'lag (min)')
            ylabel(app.CC11, 'RNAP2')
            app.CC22 = uiaxes(app.ACCTab);
            title(app.CC22, 'Ser5ph ACC')
            xlabel(app.CC22, 'lag (min)')
            ylabel(app.CC22, 'Ser5')
            app.ACCNormalizationDropDownLabel = uilabel(app.ACCTab);
            app.ACCNormalizationDropDownLabel.Text = 'ACC Normalization';
            app.ACCNormalizationDropDown = uidropdown(app.ACCTab);
            app.ACCNormalizationDropDown.Items = {'G1', 'G0_intp', 'G0', 'none'};
            app.ACCNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @acc_norm_changed, true);
            app.ACCNormalizationDropDown.Value = 'G0_intp';
            app.PlottingOptionsLabel = uilabel(app.ACCTab);
            app.PlottingOptionsLabel.Text = 'Plotting Options';
            app.simulatedspotsgreyLabel = uilabel(app.ACCTab);
            app.simulatedspotsgreyLabel.Text = '# simulated spots (grey):';
            app.NSpots_3 = uieditfield(app.ACCTab, 'numeric');
            app.NSpots_3.Limits = [1 1000];
            app.NSpots_3.RoundFractionalValues = 'on';
            app.NSpots_3.Value = 5;
            app.RunButton = uibutton(app.ACCTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UITable = uitable(app.ACCTab);
            app.UITable.ColumnName = {'source'; 'error'; ''; ''};
            app.UITable.RowName = {};
            app.FitLabel = uilabel(app.ACCTab);
            app.FitLabel.Text = 'Fit';
            app.CCTab = uitab(app.TabGroup3);
            app.CCTab.Title = 'CC';
            app.CC12 = uiaxes(app.CCTab);
            title(app.CC12, 'Ser5ph')
            xlabel(app.CC12, '')
            ylabel(app.CC12, 'CTD signal')
            app.CC23 = uiaxes(app.CCTab);
            title(app.CC23, 'mRNA')
            xlabel(app.CC23, '')
            ylabel(app.CC23, 'Ser5ph')
            app.CC13 = uiaxes(app.CCTab);
            title(app.CC13, 'mRNA')
            xlabel(app.CC13, '')
            ylabel(app.CC13, 'CTD Signal')
            app.DataOptionsLabel_2 = uilabel(app.CCTab);
            app.DataOptionsLabel_2.Text = 'Data Options';
            app.CCNormalizationDropDownLabel = uilabel(app.CCTab);
            app.CCNormalizationDropDownLabel.Text = 'CC Normalization';
            app.CCNormalizationDropDown = uidropdown(app.CCTab);
            app.CCNormalizationDropDown.Items = {'G0', 'Max', 'none'};
            app.CCNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @replot_ccs, true);
            app.CCNormalizationDropDown.Value = 'G0';
            app.InhibsTab = uitab(app.TabGroup);
            app.InhibsTab.Title = 'Inhibs';
            app.TRfig = uiaxes(app.InhibsTab);
            title(app.TRfig, 'Inhibitor Experiment')
            xlabel(app.TRfig, 'Time (min)')
            ylabel(app.TRfig, 'Signals')
            app.NumberofSpotstoAverageEditFieldLabel = uilabel(app.InhibsTab);
            app.NumberofSpotstoAverageEditFieldLabel.Text = 'Number of Spots to Average:';
            app.NSpots = uieditfield(app.InhibsTab, 'numeric');
            app.NSpots.Limits = [1 1000];
            app.NSpots.RoundFractionalValues = 'on';
            app.NSpots.Value = 1;
            app.run_inhibs = uibutton(app.InhibsTab, 'push');
            app.run_inhibs.ButtonPushedFcn = createCallbackFcn(app, @Run_inhib, true);
            app.run_inhibs.Text = 'Run';
            app.BlockLabel = uilabel(app.InhibsTab);
            app.BlockLabel.Text = 'Block:';
            app.WeightLabel = uilabel(app.InhibsTab);
            app.WeightLabel.Text = 'Weight:';
            app.beta_per = uieditfield(app.InhibsTab, 'numeric');
            app.beta_per.Value = 1;
            app.omega_per = uieditfield(app.InhibsTab, 'numeric');
            app.omega_per.Value = 1;
            app.esc_per = uieditfield(app.InhibsTab, 'numeric');
            app.esc_per.Value = 1;
            app.comp_per = uieditfield(app.InhibsTab, 'numeric');
            app.comp_per.Value = 1;
            app.abort_per = uieditfield(app.InhibsTab, 'numeric');
            app.abort_per.Value = 1;
            app.BurstsizeLabel = uilabel(app.InhibsTab);
            app.BurstsizeLabel.Text = 'Burst size';
            app.BurstFreqLabel = uilabel(app.InhibsTab);
            app.BurstFreqLabel.Text = 'Burst Freq';
            app.kescLabel = uilabel(app.InhibsTab);
            app.kescLabel.Text = 'k esc';
            app.kabortLabel = uilabel(app.InhibsTab);
            app.kabortLabel.Text = 'k abort';
            app.kcompleteLabel = uilabel(app.InhibsTab);
            app.kcompleteLabel.Text = 'k complete';
            app.CTDLabel_2 = uilabel(app.InhibsTab);
            app.CTDLabel_2.Text = 'CTD';
            app.Label_16 = uilabel(app.InhibsTab);
            app.Label_16.Text = '■';
            app.Label_17 = uilabel(app.InhibsTab);
            app.Label_17.Text = '■';
            app.TSLabel = uilabel(app.InhibsTab);
            app.TSLabel.Text = 'TS';
            app.k_mrna_release_per = uieditfield(app.InhibsTab, 'numeric');
            app.k_mrna_release_per.Value = 1;
            app.kmRNAreleaseLabel = uilabel(app.InhibsTab);
            app.kmRNAreleaseLabel.Text = 'k mRNA release';
            app.k_mrna_proc_per = uieditfield(app.InhibsTab, 'numeric');
            app.k_mrna_proc_per.Value = 1;
            app.kmRNAprocLabel = uilabel(app.InhibsTab);
            app.kmRNAprocLabel.Text = 'k mRNA proc';
            app.k_ctd_to_ser5_per = uieditfield(app.InhibsTab, 'numeric');
            app.k_ctd_to_ser5_per.Value = 1;
            app.kctdtoser5phctdLabel = uilabel(app.InhibsTab);
            app.kctdtoser5phctdLabel.Text = 'k ctd to ser5ph+ctd';
            app.Ser5phforser5phratemodelLabel = uilabel(app.InhibsTab);
            app.Ser5phforser5phratemodelLabel.Text = 'Ser5ph (for ser5ph rate model)';
            app.Label_18 = uilabel(app.InhibsTab);
            app.Label_18.Text = '■';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.TabGroup2 = uitabgroup(app.StatsTab);
            app.StatisticsIntensityDistributionsTab = uitab(app.TabGroup2);
            app.StatisticsIntensityDistributionsTab.Title = 'Statistics / Intensity Distributions';
            app.UITable2 = uitable(app.StatisticsIntensityDistributionsTab);
            app.UITable2.ColumnName = {'u TS'; 'u SER5'; 'u POL2'};
            app.UITable2.RowName = {};
            app.mrna_hist = uiaxes(app.StatisticsIntensityDistributionsTab);
            title(app.mrna_hist, 'mRNA histograms')
            xlabel(app.mrna_hist, 'Molecule Counts')
            ylabel(app.mrna_hist, 'Probability')
            app.ser5_hist = uiaxes(app.StatisticsIntensityDistributionsTab);
            title(app.ser5_hist, 'ser5ph signal histograms')
            xlabel(app.ser5_hist, 'Normalized Intensity')
            ylabel(app.ser5_hist, 'Y')
            app.rnap_hist = uiaxes(app.StatisticsIntensityDistributionsTab);
            title(app.rnap_hist, 'CTD  signal histograms')
            xlabel(app.rnap_hist, 'Normalized Intensity')
            ylabel(app.rnap_hist, 'Probability')
            app.NumberofSpotstoAverageEditFieldLabel_2 = uilabel(app.StatisticsIntensityDistributionsTab);
            app.NumberofSpotstoAverageEditFieldLabel_2.Text = 'Number of Spots to Average:';
            app.NSpots_2 = uieditfield(app.StatisticsIntensityDistributionsTab, 'numeric');
            app.NSpots_2.Limits = [1 1000];
            app.NSpots_2.RoundFractionalValues = 'on';
            app.NSpots_2.Value = 15;
            app.hist_simulate = uibutton(app.StatisticsIntensityDistributionsTab, 'push');
            app.hist_simulate.ButtonPushedFcn = createCallbackFcn(app, @generate_hist, true);
            app.hist_simulate.Text = 'Simulate';
            app.DataLabel_3 = uilabel(app.StatisticsIntensityDistributionsTab);
            app.DataLabel_3.Text = 'Data';
            app.Label_14 = uilabel(app.StatisticsIntensityDistributionsTab);
            app.Label_14.Text = '■';
            app.Label_15 = uilabel(app.StatisticsIntensityDistributionsTab);
            app.Label_15.Text = '■';
            app.SimulationLabel = uilabel(app.StatisticsIntensityDistributionsTab);
            app.SimulationLabel.Text = 'Simulation';
            app.SignalIntensityMoleculecountHistogramcomparisonsLabel = uilabel(app.StatisticsIntensityDistributionsTab);
            app.SignalIntensityMoleculecountHistogramcomparisonsLabel.Text = 'Signal Intensity / Molecule count Histogram comparisons';
            app.mRNAsignalsarecomparedwiththeLabel = uilabel(app.StatisticsIntensityDistributionsTab);
            app.mRNAsignalsarecomparedwiththeLabel.Text = {'mRNA signals are compared with the actual molecule counts from '; 'experiments while the CTD and Ser5ph are compared with intensity '; 'distrubtions from fluorescent microscopy.'};
            app.Stattable1 = uitable(app.StatisticsIntensityDistributionsTab);
            app.Stattable1.ColumnName = {'Statistic'; 'Value'; 'Units'};
            app.Stattable1.RowName = {};
            app.calc_stats = uibutton(app.StatisticsIntensityDistributionsTab, 'push');
            app.calc_stats.ButtonPushedFcn = createCallbackFcn(app, @fill_in_stats, true);
            app.calc_stats.Text = 'Calculate';
            app.ChipSeqTab = uitab(app.TabGroup);
            app.ChipSeqTab.Title = 'Chip Seq';
            app.chipseq_POL2 = uiaxes(app.ChipSeqTab);
            title(app.chipseq_POL2, 'Steady State')
            xlabel(app.chipseq_POL2, 'Location (Kilobases)')
            ylabel(app.chipseq_POL2, 'Molecule Count')
            app.chipseq_SER5 = uiaxes(app.ChipSeqTab);
            title(app.chipseq_SER5, 'On (free CTD > 10)')
            xlabel(app.chipseq_SER5, 'Location (Kilobases)')
            ylabel(app.chipseq_SER5, 'Molecule Count')
            app.chipseq_SER5_2 = uiaxes(app.ChipSeqTab);
            title(app.chipseq_SER5_2, 'Off (free CTD < 10)')
            xlabel(app.chipseq_SER5_2, 'Location (Kilobases)')
            ylabel(app.chipseq_SER5_2, 'Molecule Count')
            app.BinsizeEditFieldLabel = uilabel(app.ChipSeqTab);
            app.BinsizeEditFieldLabel.Text = 'Bin size';
            app.BinsizeEditField = uieditfield(app.ChipSeqTab, 'numeric');
            app.BinsizeEditField.Limits = [1 10000];
            app.BinsizeEditField.RoundFractionalValues = 'on';
            app.BinsizeEditField.Value = 10;
            app.run_chip = uibutton(app.ChipSeqTab, 'push');
            app.run_chip.ButtonPushedFcn = createCallbackFcn(app, @run_chip_ana, true);
            app.run_chip.Text = 'Run';
            app.NspotsEditFieldLabel = uilabel(app.ChipSeqTab);
            app.NspotsEditFieldLabel.Text = 'Nspots';
            app.NspotsEditField = uieditfield(app.ChipSeqTab, 'numeric');
            app.NspotsEditField.Value = 3;
            app.KelongEditFieldLabel = uilabel(app.ChipSeqTab);
            app.KelongEditFieldLabel.Text = 'K elong';
            app.KelongEditField = uieditfield(app.ChipSeqTab, 'numeric');
            app.KelongEditField.Value = 4.1;
            app.SimulatedChipExperimentsLabel = uilabel(app.ChipSeqTab);
            app.SimulatedChipExperimentsLabel.Text = 'Simulated Chip Experiments';
            app.ThemodelisrunatlongtimeperiodsLabel = uilabel(app.ChipSeqTab);
            app.ThemodelisrunatlongtimeperiodsLabel.Text = {'The model is run at long time periods'; 'and data is taken at decorrelated time'; 'steps and based on a given kelong  '; 'middle distances are uniformly'; 'calculated from the percentage of '; 'completion time it would take to '; 'elongate. These bins are then filled in'; ' to the left.'};
            app.MinimaTab = uitab(app.TabGroup);
            app.MinimaTab.Title = 'Minima';
            app.minima_plot = uiaxes(app.MinimaTab);
            title(app.minima_plot, 'CTD')
            xlabel(app.minima_plot, 'Tau (min)')
            ylabel(app.minima_plot, 'Signal')
            app.minima_plot_2 = uiaxes(app.MinimaTab);
            title(app.minima_plot_2, 'SER5')
            xlabel(app.minima_plot_2, 'Tau (min)')
            ylabel(app.minima_plot_2, '')
            app.minima_plot_3 = uiaxes(app.MinimaTab);
            title(app.minima_plot_3, 'mRNA')
            xlabel(app.minima_plot_3, 'Tau (min)')
            ylabel(app.minima_plot_3, 'Signal')
            app.minima_plot_4 = uiaxes(app.MinimaTab);
            title(app.minima_plot_4, 'TimeCourses')
            xlabel(app.minima_plot_4, 'Tau (min)')
            ylabel(app.minima_plot_4, 'Signal')
            app.run_minima = uibutton(app.MinimaTab, 'push');
            app.run_minima.ButtonPushedFcn = createCallbackFcn(app, @run_minima_analysis, true);
            app.run_minima.Text = 'Run';
            app.detectionthresholdEditFieldLabel = uilabel(app.MinimaTab);
            app.detectionthresholdEditFieldLabel.Text = 'detection threshold';
            app.detectionthresholdEditField = uieditfield(app.MinimaTab, 'numeric');
            app.detectionthresholdEditField.Value = 0.8;
            app.minima_plot_5 = uiaxes(app.MinimaTab);
            title(app.minima_plot_5, 'Merge')
            xlabel(app.minima_plot_5, 'Tau (min)')
            ylabel(app.minima_plot_5, '')
            app.validminimaEditFieldLabel = uilabel(app.MinimaTab);
            app.validminimaEditFieldLabel.Text = 'valid minima';
            app.validminimaEditField = uieditfield(app.MinimaTab, 'text');
            app.GeneratedTrajEditFieldLabel = uilabel(app.MinimaTab);
            app.GeneratedTrajEditFieldLabel.Text = 'Generated Traj';
            app.GeneratedTrajEditField = uieditfield(app.MinimaTab, 'numeric');
            app.GeneratedTrajEditField.Value = 20;
            app.DataLabel = uilabel(app.MinimaTab);
            app.DataLabel.Text = 'Data';
            app.Label_3 = uilabel(app.MinimaTab);
            app.Label_3.Text = '■';
            app.CTDLabel = uilabel(app.MinimaTab);
            app.CTDLabel.Text = 'CTD';
            app.Label_4 = uilabel(app.MinimaTab);
            app.Label_4.Text = '■';
            app.Label_5 = uilabel(app.MinimaTab);
            app.Label_5.Text = '■';
            app.Label_6 = uilabel(app.MinimaTab);
            app.Label_6.Text = '■';
            app.SER5Label = uilabel(app.MinimaTab);
            app.SER5Label.Text = 'SER5';
            app.mRNALabel = uilabel(app.MinimaTab);
            app.mRNALabel.Text = 'mRNA';
            app.ModelLabel = uilabel(app.MinimaTab);
            app.ModelLabel.Text = 'Model';
            app.IntensityScatterTab = uitab(app.TabGroup);
            app.IntensityScatterTab.Title = 'Intensity Scatter';
            app.ctd_ks = uiaxes(app.IntensityScatterTab);
            title(app.ctd_ks, 'CTD-SER5')
            xlabel(app.ctd_ks, 'CTD')
            ylabel(app.ctd_ks, 'SER5')
            app.ser5_ks = uiaxes(app.IntensityScatterTab);
            title(app.ser5_ks, 'CTD-TS')
            xlabel(app.ser5_ks, 'CTD')
            ylabel(app.ser5_ks, 'TS')
            app.ts_ks = uiaxes(app.IntensityScatterTab);
            title(app.ts_ks, 'TS-SER5')
            xlabel(app.ts_ks, 'TS')
            ylabel(app.ts_ks, 'SER5')
            app.ksplot = uibutton(app.IntensityScatterTab, 'push');
            app.ksplot.ButtonPushedFcn = createCallbackFcn(app, @ksplotButtonPushed, true);
            app.ksplot.Text = 'Plot';
            app.DataLabel_2 = uilabel(app.IntensityScatterTab);
            app.DataLabel_2.Text = 'Data';
            app.Label_7 = uilabel(app.IntensityScatterTab);
            app.Label_7.Text = '■';
            app.ModelLabel_2 = uilabel(app.IntensityScatterTab);
            app.ModelLabel_2.Text = 'Model';
            app.Label_8 = uilabel(app.IntensityScatterTab);
            app.Label_8.Text = '■';
            app.Label_9 = uilabel(app.IntensityScatterTab);
            app.Label_9.Text = '■';
            app.Label_10 = uilabel(app.IntensityScatterTab);
            app.Label_10.Text = '■';
            app.Label_11 = uilabel(app.IntensityScatterTab);
            app.Label_11.Text = '■';
            app.NspotsEditField_2Label = uilabel(app.IntensityScatterTab);
            app.NspotsEditField_2Label.Text = 'Nspots';
            app.nspots_intensity_scatter = uieditfield(app.IntensityScatterTab, 'numeric');
            app.nspots_intensity_scatter.Value = 20;
            app.IntensityScatterPlotCorrelationsLabel = uilabel(app.IntensityScatterTab);
            app.IntensityScatterPlotCorrelationsLabel.Text = 'Intensity Scatter Plot Correlations';
            app.IntensitysovertimeareoverlayedLabel = uilabel(app.IntensityScatterTab);
            app.IntensitysovertimeareoverlayedLabel.Text = {'Intensities over time ploted as probability densities for the model simulations'; 'and by scatter plot (black) for the data for each of the 3 signals.'};
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.TriTaggedTranscriptionsystemLabel = uilabel(app.InfoTab);
            app.TriTaggedTranscriptionsystemLabel.Text = 'Tri-Tagged Transcription system';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'The system consists of 3 Fluorescent tags: on the unphosyphorylated CTD tail of POLII, Ser5 phosphorylation and on the '; 'elongating mRNA in its probe region.'; ''; 'The model works as a simple bursting model with an extra state dictated by POLII escaping / leaving via k_esc and k_abort.'; 'RNAPII comes into the cluster with bursts dictated by beta and omega, and instantly has CTD and Ser5ph signal. '; 'Upon escape, the model adds an mRNA signal for a specified completion time via k_completion. Otherwise the RNAP2 '; 'leaves the system via k_abort.'};
            app.Label_2 = uilabel(app.InfoTab);
            app.Label_2.Text = {'Live-cell imaging of the spatiotemporal dynamics of endogenous RNA polymerase II phosphorylation at a single gene'; ''; 'Authors: Linda S. Forero-Quintero, William Raymond, Tetsuya Handa, Matthew Saxton, Tatsuya Morisaki, Hiroshi Kimura, '; 'Edouard Bertrand, Brian Munsky, Timothy Stasevich '};
            app.Image3 = uiimage(app.InfoTab);
            app.Image3.ImageSource = 'simplemodel.png';
            app.LinktopaperhereLabel = uilabel(app.InfoTab);
            app.LinktopaperhereLabel.Text = 'Link to paper here';
            app.loadingbar = uiaxes(app.UIFigure);
            title(app.loadingbar, '')
            xlabel(app.loadingbar, '')
            ylabel(app.loadingbar, '')
            app.loadingbar_text = uilabel(app.UIFigure);
            app.loadingbar_text.Text = '   ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TranscriptionModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end